classdef MatGPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatGPTUIFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ChatGridLayout                  matlab.ui.container.GridLayout
        SuggestfollowupquestionsCheckBox  matlab.ui.control.CheckBox
        TestGeneratedMATLABCodeCheckBox  matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        ChatTab                         matlab.ui.container.Tab
        ChatHTML                        matlab.ui.control.HTML
        UsageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TocalculateAPIfeespleaseconsultLabel  matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        TotaltokensusedEditField        matlab.ui.control.NumericEditField
        TotaltokensusedEditFieldLabel   matlab.ui.control.Label
        PrompttokensusedEditField       matlab.ui.control.NumericEditField
        PrompttokensusedEditFieldLabel  matlab.ui.control.Label
        CompletiontokensusedEditField   matlab.ui.control.NumericEditField
        CompletiontokensusedEditFieldLabel  matlab.ui.control.Label
        PromptTextArea                  matlab.ui.control.TextArea
        ChatTree                        matlab.ui.container.Tree
        Image                           matlab.ui.control.Image
        SendButton                      matlab.ui.control.Button
        MatGPTpoweredbyOpenAIChatGPTAPILabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        SettingsGridLayout              matlab.ui.container.GridLayout
        secsLabel                       matlab.ui.control.Label
        TimeoutEditField                matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel           matlab.ui.control.Label
        KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel  matlab.ui.control.Label
        SaveKeyButton                   matlab.ui.control.Button
        YourOpenAIAPIKeyEditField       matlab.ui.control.EditField
        YourOpenAIAPIKeyEditFieldLabel  matlab.ui.control.Label
        APIKeyNotFoundLamp              matlab.ui.control.Lamp
        APIKeyNotFoundLampLabel         matlab.ui.control.Label
        PresetsListBox                  matlab.ui.control.ListBox
        PresetsListBoxLabel             matlab.ui.control.Label
        RoleTextArea                    matlab.ui.control.TextArea
        RoleTextAreaLabel               matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        MaxTokensEditField              matlab.ui.control.NumericEditField
        MaxTokensEditFieldLabel         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        gpt35turboistherecommendedstablebuildLabel  matlab.ui.control.Label
        SetOpenAIAPIKeyLabel            matlab.ui.control.Label
        OrcustomizeparametersyourselfLabel  matlab.ui.control.Label
        GetyourAPIkeyfromLabel          matlab.ui.control.Label
        ChooseapresetLabel              matlab.ui.control.Label
        StartNewChatButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        moreprecise1balanced2morecreativeLabel  matlab.ui.control.Label
        MaximumnumberoftokenstoincludeinresponseLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        AdvancedTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Sequence4EditField              matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        Sequence3EditField              matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        Sequence2EditField              matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        Sequence1EditField              matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        StopSequencesLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        SavetofileMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % chatGPT object
        Bot;
        % presets
        Presets;
        % path of the app file
        Path;
        % holds most recent test report
        TestReport;
        % holds most recent error messages
        ErrorMessages;
    end
    
    methods (Access = private)
                
        function resetChatHTML(app)
            % RESETCHATHTML - Reset the chat HTML source (happens whenever
            % switching to a previous chat or starting a new chat)
            app.ChatHTML.HTMLSource = "";
            app.ChatHTML.Data = [];
            drawnow
            app.ChatHTML.HTMLSource = fullfile(app.Path,"contents","index.html");
            drawnow
        end
        
        function addChatLog(app,role,content)
            % ADDCHATLOG - Add a row to the Chat History table by changing
            % the "Data" property. This triggers an event listener to update
            % the HTML.

            % Use most recent chat log if not specified
            if nargin<2
                data = app.ChatTree.SelectedNodes.NodeData;
                role = data.messages.role;
                content = data.messages.content;
            end
            if ismember(role,["assistant","MATLAB"])
                % convert markdown tables and code blocks to HTML
                htmlContent = TextHelper.replaceTableMarkdown(content);
                % convert ``` markdown from responses to our code style
                htmlContent = TextHelper.replaceCodeMarkdown(htmlContent);
                % Update the uihtml Data property to trigger the JavaScript 
                % listener that will add a row to the HTML table
                app.ChatHTML.Data = {role,htmlContent};
            else
                app.ChatHTML.Data = {role,content};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                try
                    %% load external files
                    % get the path of the app file
                    [app.Path,~,~] = fileparts(mfilename("fullpath"));
                    % add path to helper classes and functions
                    addpath(fullfile(app.Path,"helpers"));
                    % load presets from csv file
                    app.Presets = readtable(fullfile(app.Path,"contents","presets.csv"), ...
                        TextType="string",Delimiter=",");
                    % load chat history data
                    chatHistoryFile = fullfile(app.Path,"contents","ChatData.mat");
                    % Intialize the HTML files
                    resetChatHTML(app)
                    % display the startup message
                    role = "MatGPT";
                    content = "This app provides access to the ChatGPT API " + ...
                        "from OpenAI. To use this app, you need to obtain " + ...
                        "your own API key via <a href='https://platform.openai.com/account/api-keys'>" + ...
                        "https://platform.openai.com/account/api-keys</a>. " + ...
                        "By obtaining and using the API key in this app, " + ...
                        "you agree to OpenAI's <a href='https://openai.com/policies'>" + ...
                        "terms and policies</a>." + newline +newline + ...
                        "To get started, click ""+ New Chat"" or the name of a previous chat.";
                    addChatLog(app,role,content);
                    drawnow                  
                    % make sure the API Key is available
                    if ~isempty(getenv("OPENAI_API_KEY"))
                        app.APIKeyNotFoundLamp.Color = 'Green';
                        app.APIKeyNotFoundLampLabel.Text = 'API Key Found';
                    else
                        id = "MatGPT:missingKey";
                        msg = "No API key found. Enter your API key in " + ...
                            "the Settings to save it in an environment variable.";
                        ME = MException(id,msg);
                        throw(ME)
                    end
                catch ME
                    % Intialize the HTML files
                    % resetChatHTML(app)
                    % display error dialog
                    uialert(app.MatGPTUIFigure,ME.message,"Error");
                    app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                    % rethrow(ME)
                    return
                end
                %% Populate various fields
                % add new chat node
                if isempty(app.ChatTree.Children)
                    uitreenode(app.ChatTree,'Text','+ New Chat');
                end
                % append old chats to ChatTree
                if exist(chatHistoryFile,"file")
                    load(chatHistoryFile,"PreviousChats")
                    % add old chats to ChatTree
                    for ii = 1:height(PreviousChats)
                        topic = PreviousChats.topic(ii);
                        s = PreviousChats.chat_history(ii);
                        uitreenode(app.ChatTree,"NodeData",s,"Text",topic, ... 
                            "ContextMenu",app.ContextMenu);
                    end
                end
                % Initialize Presets ListBox
                app.PresetsListBox.Items = app.Presets.name;
                app.PresetsListBox.ItemsData = 1:height(app.Presets);
                app.PresetsListBox.Value = 1;
                % set parameter defaults
                app.ModelDropDown.Value = app.Presets.model(1);
                app.MaxTokensEditField.Value = app.Presets.max_tokens(1);
                app.TemperatureEditField.Value = app.Presets.temperature(1);
                app.RoleTextArea.Value = app.Presets.content(1);
                % enable Start New Chat button
                app.StartNewChatButton.Enable = "on";
        end

        % Button pushed function: StartNewChatButton
        function StartNewChat(app, event)
            % set parameters
            model = string(app.ModelDropDown.Value);
            role = join(string(app.RoleTextArea.Value),newline);
            max_tokens = app.MaxTokensEditField.Value;
            temperature = app.TemperatureEditField.Value;
            % instantiate the class
            try
                app.Bot = chatter(model=model, role=role, ...
                    max_tokens=max_tokens, ...
                    temperature=temperature);
            catch ME
                % display error dialog
                uialert(app.MatGPTUIFigure,ME.message,"Error");
                % rethrow(ME)
            end
            
            % add mew node to the tree
            nodeLabels = arrayfun(@(x) string(x.Text), app.ChatTree.Children);
            myChats = nodeLabels(contains(nodeLabels,"My Chat"));
            if isempty(myChats)
                n = 0;
            else
                myChats = erase(myChats,["💬 My Chat "," ...✏️"]);
                n = max(str2double(myChats));
            end
            s = struct('model',model,'max_tokens',max_tokens, ...
                'temperature',temperature,'messages',app.Bot.messages);
            uitreenode(app.ChatTree, "NodeData",s, ...
                "Text","💬 My Chat " + (n + 1) + " ...✏️", ...
                "ContextMenu",app.ContextMenu);
            % move the current chat to the second place
            app.ChatTree.Children = [app.ChatTree.Children(1);app.ChatTree.Children(end); ... 
                app.ChatTree.Children(2:end-1)];
            app.ChatTree.SelectedNodes = app.ChatTree.Children(2);
            % Intialize the HTML files
            resetChatHTML(app)
            % Initialize the chat log - this will just shows the "system" message for now
            addChatLog(app)
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            % update UI components
            app.SendButton.Enable = "on";
            n = app.PresetsListBox.Value;
            app.PromptTextArea.Value = app.Presets.prompt(n);
        end

        % Button pushed function: SendButton
        function send(app, event)
            prompt = string(app.PromptTextArea.Value);
            prompt = join(prompt,newline);
            timeout = app.TimeoutEditField.Value;
            if app.SuggestfollowupquestionsCheckBox.Value
                relatedQ = "After answering this question, suggest 3 related questions after 'Three related questions: '";
                prompt = prompt + newline + newline + relatedQ;
            end
            % get stop sequences
            stop = cell(1,4);
            stop{1} = app.Sequence1EditField.Value;
            stop{2} = app.Sequence2EditField.Value;
            stop{3} = app.Sequence3EditField.Value;
            stop{4} = app.Sequence4EditField.Value;
            stop(cellfun(@isempty,stop)) = [];
            % display progress dialog box
            progressdlg = uiprogressdlg(app.MatGPTUIFigure,"Title","Please Wait", ...
            "Message","Waiting for API Response...", ...
            "Indeterminate","on");
            % send the prompt to API            
            try
                if timeout > 10
                    if isempty(stop)
                        response = app.Bot.chat(prompt,timeout=timeout);
                    else
                        response = app.Bot.chat(prompt,timeout=timeout, stop=string(stop));
                    end
                else
                    if isempty(stop)
                        response = app.Bot.chat(prompt);
                    else
                        response = app.Bot.chat(prompt,stop=string(stop));
                    end
                end
            catch ME
                uialert(app.MatGPTUIFigure,ME.message,"Error");
                if contains(ME.message, "Check your API key.")
                    app.APIKeyNotFoundLampLabel.Text = 'API Key Not Valid';
                    app.APIKeyNotFoundLamp.Color = 'Red';
                    app.StartNewChatButton.Enable = "off";
                    app.ChatTree.SelectedNodes.delete;
                    app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                end
                return
                % rethrow(ME)
            end
            
            % extract three related questions
            if contains(response,"Three related questions:")
                answer = extractBefore(response,"Three related questions:");
                answer = strtrim(answer);
                app.Bot.messages(end-1).content = prompt;
                app.Bot.messages(end).content = answer;
                relquestions = extractAfter(response,"Three related questions:");
                relquestions = "Suggested follow-up questions:" + newline + strtrim(relquestions);
            end
            
            % add messages to the current chat
            selectedChat = app.ChatTree.SelectedNodes;
            selectedChat.NodeData.messages = app.Bot.messages;
            % extract messages struct and add to HTML
            m = app.Bot.messages;
            addChatLog(app,m(end-1).role,m(end-1).content);
            drawnow
            pause(1/20)
            addChatLog(app,m(end).role,m(end).content);
            drawnow
            % add related questions
            if exist('relquestions','var')
                app.PromptTextArea.Value = relquestions;
            else
                app.PromptTextArea.Value = "";
            end
            % display the tokens used and cost
            [completion_tokens,prompt_tokens,total_tokens] = app.Bot.usage();
            app.CompletiontokensusedEditField.Value = completion_tokens;
            app.PrompttokensusedEditField.Value = prompt_tokens;
            app.TotaltokensusedEditField.Value = total_tokens;

            % If Validated Generated MATLAB code checkbox is checked 
            app.ErrorMessages = {};
            if app.TestGeneratedMATLABCodeCheckBox.Value
                % Upate the progress dialog
                progressdlg.Message = progressdlg.Message + " Done" + newline + "Testing code... ";
                % set up code checker
                checker = CodeChecker(response);
                % run checks
                runChecks(checker); 
                app.ErrorMessages = checker.ErrorMessages;                
                % add test report to chat log
                addChatLog(app,"MATLAB",checker.Report);
                drawnow
                % upate the messages object in chatter class
                app.Bot.injectChatLog("MATLAB",checker.Report);
                % update ChatTree data
                selectedChat.NodeData.messages = app.Bot.messages;
            end

            % close the progress bar
            progressdlg.Message = [progressdlg.Message ' Done'];
            close(progressdlg);
        end

        % Value changed function: PresetsListBox
        function setPreset(app, event)
            n = app.PresetsListBox.Value;
            app.ModelDropDown.Value = app.Presets.model(n);
            app.MaxTokensEditField.Value = app.Presets.max_tokens(n);
            app.TemperatureEditField.Value = app.Presets.temperature(n);
            app.RoleTextArea.Value = app.Presets.content(n);
            app.PromptTextArea.Value = app.Presets.prompt(n);
            app.TestGeneratedMATLABCodeCheckBox.Value = app.Presets.test_code(n);
        end

        % Selection changed function: ChatTree
        function ChatTreeSelectionChanged(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            % if New Chat
            if selectedNodes == app.ChatTree.Children(1)
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                resetChatHTML(app);
                drawnow
            % if previous chat
            else
                % Reset the HTML table            
                resetChatHTML(app)
                % extact node data
                model = selectedNodes.NodeData.model;
                max_tokens = selectedNodes.NodeData.max_tokens;
                temperature = selectedNodes.NodeData.temperature;
                messages = selectedNodes.NodeData.messages;
                role = messages(1).content;
                % instantiate the chat bot
                try
                app.Bot = chatter(model=model, role=role, ...
                    max_tokens=max_tokens, ...
                    temperature=temperature);
                catch ME
                    % display error dialog
                    uialert(app.MatGPTUIFigure,ME.message,"Error");
                    rethrow(ME)
                end
                % add chat history to the bot
                app.Bot.messages = messages;               
                % extract messages struct and add to HTML
                for ii = 1:numel(messages)
                    role = messages(ii).role;
                    content = messages(ii).content;
                    addChatLog(app,role,content)
                    drawnow
                    pause(1/20)
                end
                app.SendButton.Enable = "on";
            end
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            selectedChat = app.ChatTree.SelectedNodes;
            prompt = 'Enter new name';
            dlgtitle = 'Rename Chat';
            definput = selectedChat.Text;
            definput = cellstr(erase(definput,["💬 ","...✏️"]));
            answer = string(inputdlg(prompt,dlgtitle,[1 40],definput));
            if answer ~= ""
                selectedChat.Text = "💬 " + answer + "...✏️";
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            if isempty(selectedNodes)
                prompt = 'Please select a chat to delete.';
                dlgtitle = 'No chat selected';
                answer = questdlg(prompt,dlgtitle,'Cancel','Cancel');
            else
                prompt = 'Are you sure you want to delete the chat?';
                dlgtitle = 'Confirm your choice';
                answer = questdlg(prompt,dlgtitle,'Delete','Cancel','Cancel');
                switch answer
                    case 'Delete'
                        
                        selectedNodes.delete;
                        resetChatHTML(app);
                        drawnow
                        app.PromptTextArea.Value = "";
                        app.SendButton.Enable = "off";
                end
            end
        end

        % Menu selected function: SavetofileMenu
        function SaveChatMenuSelected(app, event)
            filename = uiputfile('chat history.txt');
            if ~isequal(filename,0)
                selectedNodes = app.ChatTree.SelectedNodes;
                messages = selectedNodes.NodeData.messages;
                roles = (arrayfun(@(x) x.role, messages))';
                contents = (arrayfun(@(x) x.content, messages))';
                str = pad(roles,max(strlength(roles))) + ": " + contents;
                writelines(str,filename)
            end
        end

        % Close request function: MatGPTUIFigure
        function MatGPTUIFigureCloseRequest(app, event)
            topics = arrayfun(@(x) string(x.Text), app.ChatTree.Children(2:end));
            chat_history = arrayfun(@(x) x.NodeData, app.ChatTree.Children(2:end));
            PreviousChats = table;
            PreviousChats.topic = topics;
            PreviousChats.chat_history = chat_history;
            % make sure the message object contains at least 3 messages
            isValid = arrayfun(@(x) numel(x.messages), PreviousChats.chat_history) >= 3;
            PreviousChats(~isValid,:) = [];            
            % if there is any messages left
            if numel(PreviousChats.topic) > 0
                save(fullfile(app.Path,"contents","ChatData.mat"),"PreviousChats")
            else
                if exist(fullfile(app.Path,"contents","ChatData.mat"),"file")
                    delete(fullfile(app.Path,"contents","ChatData.mat"));
                end
            end
            delete(app)
        end

        % Button pushed function: SaveKeyButton
        function SaveKeyButtonPushed(app, event)
            apikey = app.YourOpenAIAPIKeyEditField.Value;
            if ~isempty(apikey)
                setenv("OPENAI_API_KEY",apikey)
                app.YourOpenAIAPIKeyEditField.Value = "";
            end
            startupFcn(app)
        end

        % Double-clicked callback: ChatTree
        function ChatTreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            location = event.InteractionInformation.Location;
            cm = node.ContextMenu;
            open(cm,location)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MatGPTUIFigure = uifigure('Visible', 'off');
            app.MatGPTUIFigure.Name = 'MatGPT';
            app.MatGPTUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatGPTUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MatGPTUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChatGridLayout = uigridlayout(app.MainTab);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel = uilabel(app.ChatGridLayout);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel.Text = 'MatGPT powered by OpenAI™ ChatGPT API';
            app.SendButton = uibutton(app.ChatGridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @send, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.Image = uiimage(app.ChatGridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'MatGPTlogo.png');
            app.ChatTree = uitree(app.ChatGridLayout);
            app.ChatTree.SelectionChangedFcn = createCallbackFcn(app, @ChatTreeSelectionChanged, true);
            app.ChatTree.DoubleClickedFcn = createCallbackFcn(app, @ChatTreeDoubleClicked, true);
            app.PromptTextArea = uitextarea(app.ChatGridLayout);
            app.TabGroup2 = uitabgroup(app.ChatGridLayout);
            app.ChatTab = uitab(app.TabGroup2);
            app.ChatTab.Title = 'Chat';
            app.ChatHTML = uihtml(app.ChatTab);
            app.UsageTab = uitab(app.TabGroup2);
            app.UsageTab.Title = 'Usage';
            app.GridLayout = uigridlayout(app.UsageTab);
            app.CompletiontokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.CompletiontokensusedEditFieldLabel.Text = 'Completion tokens used';
            app.CompletiontokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.CompletiontokensusedEditField.Editable = 'off';
            app.PrompttokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.PrompttokensusedEditFieldLabel.Text = 'Prompt tokens used';
            app.PrompttokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.PrompttokensusedEditField.Editable = 'off';
            app.TotaltokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.TotaltokensusedEditFieldLabel.Text = 'Total tokens used';
            app.TotaltokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.Hyperlink2 = uihyperlink(app.GridLayout);
            app.Hyperlink2.URL = 'https://openai.com/pricing';
            app.Hyperlink2.Text = 'OPENAI pricing page';
            app.TocalculateAPIfeespleaseconsultLabel = uilabel(app.GridLayout);
            app.TocalculateAPIfeespleaseconsultLabel.Text = 'To calculate API fees, please consult';
            app.TestGeneratedMATLABCodeCheckBox = uicheckbox(app.ChatGridLayout);
            app.TestGeneratedMATLABCodeCheckBox.Text = 'Test Generated MATLAB Code';
            app.SuggestfollowupquestionsCheckBox = uicheckbox(app.ChatGridLayout);
            app.SuggestfollowupquestionsCheckBox.Text = 'Suggest follow-up questions';
            app.SuggestfollowupquestionsCheckBox.Value = true;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGridLayout = uigridlayout(app.SettingsTab);
            app.Hyperlink = uihyperlink(app.SettingsGridLayout);
            app.Hyperlink.URL = 'https://platform.openai.com/account/api-keys';
            app.Hyperlink.Text = 'https://platform.openai.com/account/api-keys';
            app.MaximumnumberoftokenstoincludeinresponseLabel = uilabel(app.SettingsGridLayout);
            app.MaximumnumberoftokenstoincludeinresponseLabel.Text = 'Maximum number of tokens to include in response';
            app.moreprecise1balanced2morecreativeLabel = uilabel(app.SettingsGridLayout);
            app.moreprecise1balanced2morecreativeLabel.Text = '0 = more precise, 1 = balanced, 2 = more creative ';
            app.Label = uilabel(app.SettingsGridLayout);
            app.Label.Text = 'The role you assign to the chatbot. The default is an AI assistant.';
            app.StartNewChatButton = uibutton(app.SettingsGridLayout, 'push');
            app.StartNewChatButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewChat, true);
            app.StartNewChatButton.Enable = 'off';
            app.StartNewChatButton.Text = 'Start New Chat';
            app.ChooseapresetLabel = uilabel(app.SettingsGridLayout);
            app.ChooseapresetLabel.Text = 'Choose a preset';
            app.GetyourAPIkeyfromLabel = uilabel(app.SettingsGridLayout);
            app.GetyourAPIkeyfromLabel.Text = 'Get your API key from ';
            app.OrcustomizeparametersyourselfLabel = uilabel(app.SettingsGridLayout);
            app.OrcustomizeparametersyourselfLabel.Text = 'Or customize parameters yourself';
            app.SetOpenAIAPIKeyLabel = uilabel(app.SettingsGridLayout);
            app.SetOpenAIAPIKeyLabel.Text = 'Set OpenAI API Key';
            app.gpt35turboistherecommendedstablebuildLabel = uilabel(app.SettingsGridLayout);
            app.gpt35turboistherecommendedstablebuildLabel.Text = 'gpt-3.5-turbo is the recommended stable build';
            app.ModelDropDownLabel = uilabel(app.SettingsGridLayout);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SettingsGridLayout);
            app.ModelDropDown.Items = {'gpt-3.5-turbo', 'gpt-3.5-turbo-0613', 'gpt-4', 'gpt-4-0613', 'gpt-4-32k', 'gpt-4-32k-0613'};
            app.ModelDropDown.Value = 'gpt-3.5-turbo';
            app.MaxTokensEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.MaxTokensEditFieldLabel.Text = 'Max Tokens';
            app.MaxTokensEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.MaxTokensEditField.Limits = [1 4000];
            app.MaxTokensEditField.Value = 1000;
            app.TemperatureEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TemperatureEditField.Limits = [0 Inf];
            app.TemperatureEditField.Value = 1;
            app.RoleTextAreaLabel = uilabel(app.SettingsGridLayout);
            app.RoleTextAreaLabel.Text = 'Role';
            app.RoleTextArea = uitextarea(app.SettingsGridLayout);
            app.PresetsListBoxLabel = uilabel(app.SettingsGridLayout);
            app.PresetsListBoxLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.SettingsGridLayout);
            app.PresetsListBox.Items = {};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @setPreset, true);
            app.PresetsListBox.Value = {};
            app.APIKeyNotFoundLampLabel = uilabel(app.SettingsGridLayout);
            app.APIKeyNotFoundLampLabel.Text = 'API Key Not Found';
            app.APIKeyNotFoundLamp = uilamp(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel.Text = 'Your OpenAI API Key';
            app.YourOpenAIAPIKeyEditField = uieditfield(app.SettingsGridLayout, 'text');
            app.SaveKeyButton = uibutton(app.SettingsGridLayout, 'push');
            app.SaveKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKeyButtonPushed, true);
            app.SaveKeyButton.Text = 'Save Key';
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel = uilabel(app.SettingsGridLayout);
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel.Text = 'Key will be saved in environment variable "OPENAI_API_Key"';
            app.TimeoutEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TimeoutEditField.Value = 10;
            app.secsLabel = uilabel(app.SettingsGridLayout);
            app.secsLabel.Text = 'secs';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.GridLayout2 = uigridlayout(app.AdvancedTab);
            app.StopSequencesLabel = uilabel(app.GridLayout2);
            app.StopSequencesLabel.Text = 'Stop Sequences';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = ' Sequence 1';
            app.Sequence1EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = ' Sequence 2';
            app.Sequence2EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = ' Sequence 3';
            app.Sequence3EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = 'Sequence 4';
            app.Sequence4EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = 'Specify the sequences where the API will stop generating further tokens';
            app.ContextMenu = uicontextmenu(app.MatGPTUIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.SavetofileMenu = uimenu(app.ContextMenu);
            app.SavetofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChatMenuSelected, true);
            app.SavetofileMenu.Text = 'Save to file';
            % Show the figure after all components are created
            app.MatGPTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatGPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatGPTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatGPTUIFigure)
        end
    end
end