classdef InsertExperimentalData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MicrobesTab                     matlab.ui.container.Tab
        MicrobesTable                   matlab.ui.control.Table
        MicrobesTableLabel              matlab.ui.control.Label
        EditmicrobeLabel                matlab.ui.control.Label
        EditMicrobeInfoDropdown         matlab.ui.control.DropDown
        MicrobeIDEditFieldLabel         matlab.ui.control.Label
        MicrobeIDEditField              matlab.ui.control.EditField
        LoadMicrobeButton               matlab.ui.control.Button
        NCBITaxonomyIDEditFieldLabel    matlab.ui.control.Label
        NCBITaxonomyIDEditField         matlab.ui.control.EditField
        KingdomEditFieldLabel           matlab.ui.control.Label
        superkingdomEditField           matlab.ui.control.EditField
        PhylumEditFieldLabel            matlab.ui.control.Label
        phylumEditField                 matlab.ui.control.EditField
        ClassEditFieldLabel             matlab.ui.control.Label
        classEditField                  matlab.ui.control.EditField
        OrderEditFieldLabel             matlab.ui.control.Label
        orderEditField                  matlab.ui.control.EditField
        FamilyEditFieldLabel            matlab.ui.control.Label
        familyEditField                 matlab.ui.control.EditField
        GenusEditFieldLabel             matlab.ui.control.Label
        genusEditField                  matlab.ui.control.EditField
        SpeciesEditFieldLabel           matlab.ui.control.Label
        speciesEditField                matlab.ui.control.EditField
        LoadNCBItaxonomyButton          matlab.ui.control.Button
        UpdatetableButton               matlab.ui.control.Button
        SavedatatofilesButton           matlab.ui.control.Button
        TaxonomyLabel                   matlab.ui.control.Label
        FermentationTab                 matlab.ui.container.Tab
        OverviewofFermentationPathwaysinMicrobeListLabel  matlab.ui.control.Label
        EditmicrobeDropDownLabel        matlab.ui.control.Label
        EditMicrobeFermDropdown         matlab.ui.control.DropDown
        FermentationTable               matlab.ui.control.Table
        AcetatekinaseacetateproducerorconsumerCheckBox  matlab.ui.control.CheckBox
        BifidshuntCheckBox              matlab.ui.control.CheckBox
        AcetogenpathwayCheckBox         matlab.ui.control.CheckBox
        FormateproducerCheckBox         matlab.ui.control.CheckBox
        DlactateproducerorconsumerCheckBox  matlab.ui.control.CheckBox
        LlactateproducerorconsumerCheckBox  matlab.ui.control.CheckBox
        EthanolproducerorconsumerCheckBox  matlab.ui.control.CheckBox
        SuccinateproducerCheckBox       matlab.ui.control.CheckBox
        PropionatefromsuccinateCheckBox  matlab.ui.control.CheckBox
        Propionatefrompropane12diolCheckBox  matlab.ui.control.CheckBox
        PropionatefromlactateacrylatepathwayCheckBox  matlab.ui.control.CheckBox
        PropionatefromthreonineCheckBox  matlab.ui.control.CheckBox
        ButyrateviabutyrylCoAacetateCoAtransferaseCheckBox  matlab.ui.control.CheckBox
        ButyrateviabutyratekinaseCheckBox  matlab.ui.control.CheckBox
        ButyratefromlysineviabutyrateacetoacetateCoAtransferaseCheckBox  matlab.ui.control.CheckBox
        ButyratefromglutarateorglutamateCheckBox  matlab.ui.control.CheckBox
        Butyratefrom4hydroxybutyrateorsuccinateCheckBox  matlab.ui.control.CheckBox
        HydrogenfromferredoxinoxidoreductaseCheckBox  matlab.ui.control.CheckBox
        HydrogenfromformatehydrogenlyaseCheckBox  matlab.ui.control.CheckBox
        MethanogenesisCheckBox          matlab.ui.control.CheckBox
        SulfatereducerCheckBox          matlab.ui.control.CheckBox
        IsobutyrateproducerCheckBox     matlab.ui.control.CheckBox
        IsovalerateproducerCheckBox     matlab.ui.control.CheckBox
        AcetoinproducerCheckBox         matlab.ui.control.CheckBox
        butanediolproducerCheckBox      matlab.ui.control.CheckBox
        IndoleproducerCheckBox          matlab.ui.control.CheckBox
        PhenylacetateproducerCheckBox   matlab.ui.control.CheckBox
        ButanolproducerCheckBox         matlab.ui.control.CheckBox
        LoadFermentationPathways        matlab.ui.control.Button
        SavedataButtonFerm              matlab.ui.control.Button
        EnterDataButtonFerm             matlab.ui.control.Button
        CarbonSourcesTab                matlab.ui.container.Tab
        CarbonSourcesTable              matlab.ui.control.Table
        OverviewofCarbonSourcesinMicrobeListLabel  matlab.ui.control.Label
        EditMicrobeFermDropdown_2Label_2  matlab.ui.control.Label
        EditMicrobeCarbDropdown         matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        SimplesugarsTab                 matlab.ui.container.Tab
        DarabinoseCheckBox              matlab.ui.control.CheckBox
        LarabinoseCheckBox              matlab.ui.control.CheckBox
        DxyloseCheckBox                 matlab.ui.control.CheckBox
        SucroseCheckBox                 matlab.ui.control.CheckBox
        CellobioseCheckBox              matlab.ui.control.CheckBox
        MannoseCheckBox                 matlab.ui.control.CheckBox
        DglucoseCheckBox                matlab.ui.control.CheckBox
        FructoseCheckBox                matlab.ui.control.CheckBox
        DmaltoseCheckBox                matlab.ui.control.CheckBox
        DglucosamineCheckBox            matlab.ui.control.CheckBox
        NacetylglucosamineCheckBox      matlab.ui.control.CheckBox
        DriboseCheckBox                 matlab.ui.control.CheckBox
        GalactoseCheckBox               matlab.ui.control.CheckBox
        LactoseCheckBox                 matlab.ui.control.CheckBox
        TrehaloseCheckBox               matlab.ui.control.CheckBox
        MannitolCheckBox                matlab.ui.control.CheckBox
        MelibioseCheckBox               matlab.ui.control.CheckBox
        RaffinoseCheckBox               matlab.ui.control.CheckBox
        DgluconateEntnerDoudoroffpathwayCheckBox  matlab.ui.control.CheckBox
        SalicinCheckBox                 matlab.ui.control.CheckBox
        DglucuronicacidCheckBox         matlab.ui.control.CheckBox
        LrhamnoseCheckBox               matlab.ui.control.CheckBox
        DgalacturonicacidCheckBox       matlab.ui.control.CheckBox
        LfucoseCheckBox                 matlab.ui.control.CheckBox
        NacetylneuraminicacidCheckBox   matlab.ui.control.CheckBox
        NacetylgalactosamineCheckBox    matlab.ui.control.CheckBox
        LlyxoseCheckBox                 matlab.ui.control.CheckBox
        DeoxyriboseCheckBox             matlab.ui.control.CheckBox
        OligoandpolysaccharidesTab      matlab.ui.container.Tab
        FructooligosaccaridesCheckBox   matlab.ui.control.CheckBox
        GlycogenCheckBox                matlab.ui.control.CheckBox
        InulinCheckBox                  matlab.ui.control.CheckBox
        AmylopectinCheckBox             matlab.ui.control.CheckBox
        AmyloseCheckBox                 matlab.ui.control.CheckBox
        PullulanCheckBox                matlab.ui.control.CheckBox
        StarchCheckBox                  matlab.ui.control.CheckBox
        XylanCheckBox                   matlab.ui.control.CheckBox
        MucinCheckBox                   matlab.ui.control.CheckBox
        ArabinogalactanCheckBox         matlab.ui.control.CheckBox
        ArabinoxylanCheckBox            matlab.ui.control.CheckBox
        DextranCheckBox                 matlab.ui.control.CheckBox
        PectinCheckBox                  matlab.ui.control.CheckBox
        ArabinanCheckBox                matlab.ui.control.CheckBox
        CelluloseCheckBox               matlab.ui.control.CheckBox
        HyaluronanCheckBox              matlab.ui.control.CheckBox
        PecticgalactanCheckBox          matlab.ui.control.CheckBox
        ChondroitinsulfateCheckBox      matlab.ui.control.CheckBox
        HeparinCheckBox                 matlab.ui.control.CheckBox
        betaGlucanCheckBox              matlab.ui.control.CheckBox
        GalactomannanCheckBox           matlab.ui.control.CheckBox
        LaminarinCheckBox               matlab.ui.control.CheckBox
        GlucomannanCheckBox             matlab.ui.control.CheckBox
        HomogalacturonanCheckBox        matlab.ui.control.CheckBox
        LevanCheckBox                   matlab.ui.control.CheckBox
        alphaMannanCheckBox             matlab.ui.control.CheckBox
        LicheninCheckBox                matlab.ui.control.CheckBox
        ResistantstarchCheckBox         matlab.ui.control.CheckBox
        RhamnogalacturonanICheckBox     matlab.ui.control.CheckBox
        RhamnogalacturonanIICheckBox    matlab.ui.control.CheckBox
        XyloglucanCheckBox              matlab.ui.control.CheckBox
        AminoacidsTab                   matlab.ui.container.Tab
        SticklandreactionCheckBox       matlab.ui.control.CheckBox
        LalanineCheckBox                matlab.ui.control.CheckBox
        LarginineCheckBox               matlab.ui.control.CheckBox
        LasparagineCheckBox             matlab.ui.control.CheckBox
        LaspartateCheckBox              matlab.ui.control.CheckBox
        LcysteineCheckBox               matlab.ui.control.CheckBox
        LglutamateCheckBox              matlab.ui.control.CheckBox
        LglutamineCheckBox              matlab.ui.control.CheckBox
        LhistidineCheckBox              matlab.ui.control.CheckBox
        LisoleucineCheckBox             matlab.ui.control.CheckBox
        LleucineCheckBox                matlab.ui.control.CheckBox
        LlysineCheckBox                 matlab.ui.control.CheckBox
        LmethionineCheckBox             matlab.ui.control.CheckBox
        LornithineCheckBox              matlab.ui.control.CheckBox
        LphenylalanineCheckBox          matlab.ui.control.CheckBox
        LprolineCheckBox                matlab.ui.control.CheckBox
        LserineCheckBox                 matlab.ui.control.CheckBox
        LthreonineCheckBox              matlab.ui.control.CheckBox
        LtryptophanCheckBox             matlab.ui.control.CheckBox
        LtyrosineCheckBox               matlab.ui.control.CheckBox
        LvalineCheckBox                 matlab.ui.control.CheckBox
        OtherTab                        matlab.ui.container.Tab
        GlycerolCheckBox                matlab.ui.control.CheckBox
        SuccinateCheckBox               matlab.ui.control.CheckBox
        CitrateCheckBox                 matlab.ui.control.CheckBox
        FumarateCheckBox                matlab.ui.control.CheckBox
        LmalateCheckBox                 matlab.ui.control.CheckBox
        oxoglutarateCheckBox            matlab.ui.control.CheckBox
        ButyrateCheckBox                matlab.ui.control.CheckBox
        PropionateCheckBox              matlab.ui.control.CheckBox
        PyruvateCheckBox                matlab.ui.control.CheckBox
        CholineCheckBox                 matlab.ui.control.CheckBox
        PhenylacetateCheckBox           matlab.ui.control.CheckBox
        OxaloacetateCheckBox            matlab.ui.control.CheckBox
        IsobutyrateCheckBox             matlab.ui.control.CheckBox
        IsovalerateCheckBox             matlab.ui.control.CheckBox
        OxalateCheckBox                 matlab.ui.control.CheckBox
        LoadCarbonSources               matlab.ui.control.Button
        SavedataButtonCarb              matlab.ui.control.Button
        EnterDataButtonCarb             matlab.ui.control.Button
        NutrientrequirementsTab         matlab.ui.container.Tab
        LoadNutrientRequirements        matlab.ui.control.Button
        EditMicrobeCarbDropdown_2Label  matlab.ui.control.Label
        EditMicrobeNutrDropdown         matlab.ui.control.DropDown
        NutrientRequirementsTable       matlab.ui.control.Table
        OverviewofNutrientRequirementsinMicrobeListLabel  matlab.ui.control.Label
        SavedataButtonNutr              matlab.ui.control.Button
        EnterDataButtonNutr             matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        AminoacidsTab_2                 matlab.ui.container.Tab
        LalanineDropDownLabel           matlab.ui.control.Label
        LalanineDropDown                matlab.ui.control.DropDown
        LarginineDropDownLabel          matlab.ui.control.Label
        LarginineDropDown               matlab.ui.control.DropDown
        LasparagineDropDownLabel        matlab.ui.control.Label
        LasparagineDropDown             matlab.ui.control.DropDown
        LaspartateDropDownLabel         matlab.ui.control.Label
        LaspartateDropDown              matlab.ui.control.DropDown
        LcysteineDropDownLabel          matlab.ui.control.Label
        LcysteineDropDown               matlab.ui.control.DropDown
        GlycineDropDownLabel            matlab.ui.control.Label
        GlycineDropDown                 matlab.ui.control.DropDown
        LglutamineDropDownLabel         matlab.ui.control.Label
        LglutamineDropDown              matlab.ui.control.DropDown
        LhistidineDropDownLabel         matlab.ui.control.Label
        LhistidineDropDown              matlab.ui.control.DropDown
        LisoleucineDropDownLabel        matlab.ui.control.Label
        LisoleucineDropDown             matlab.ui.control.DropDown
        LleucineDropDownLabel           matlab.ui.control.Label
        LleucineDropDown                matlab.ui.control.DropDown
        LlysineDropDownLabel            matlab.ui.control.Label
        LlysineDropDown                 matlab.ui.control.DropDown
        LmethionineDropDownLabel        matlab.ui.control.Label
        LmethionineDropDown             matlab.ui.control.DropDown
        OrnithineDropDownLabel          matlab.ui.control.Label
        OrnithineDropDown               matlab.ui.control.DropDown
        LphenylalanineDropDownLabel     matlab.ui.control.Label
        LphenylalanineDropDown          matlab.ui.control.DropDown
        LprolineDropDownLabel           matlab.ui.control.Label
        LprolineDropDown                matlab.ui.control.DropDown
        LserineDropDownLabel            matlab.ui.control.Label
        LserineDropDown                 matlab.ui.control.DropDown
        LthreonineDropDownLabel         matlab.ui.control.Label
        LthreonineDropDown              matlab.ui.control.DropDown
        LtryptophanDropDownLabel        matlab.ui.control.Label
        LtryptophanDropDown             matlab.ui.control.DropDown
        LtyrosineDropDownLabel          matlab.ui.control.Label
        LtyrosineDropDown               matlab.ui.control.DropDown
        LvalineDropDownLabel            matlab.ui.control.Label
        LvalineDropDown                 matlab.ui.control.DropDown
        LglutamateDropDownLabel         matlab.ui.control.Label
        LglutamateDropDown              matlab.ui.control.DropDown
        VitaminsandcofactorsTab         matlab.ui.container.Tab
        aminobenzoicacidDropDownLabel   matlab.ui.control.Label
        aminobenzoicacidDropDown        matlab.ui.control.DropDown
        BiotinDropDownLabel             matlab.ui.control.Label
        BiotinDropDown                  matlab.ui.control.DropDown
        CobalaminDropDownLabel          matlab.ui.control.Label
        CobalaminDropDown               matlab.ui.control.DropDown
        FolateDropDownLabel             matlab.ui.control.Label
        FolateDropDown                  matlab.ui.control.DropDown
        HeminDropDownLabel              matlab.ui.control.Label
        HeminDropDown                   matlab.ui.control.DropDown
        NicotinamideDropDownLabel       matlab.ui.control.Label
        NicotinamideDropDown            matlab.ui.control.DropDown
        NicotinicacidDropDownLabel      matlab.ui.control.Label
        NicotinicacidDropDown           matlab.ui.control.DropDown
        PantothenateDropDownLabel       matlab.ui.control.Label
        PantothenateDropDown            matlab.ui.control.DropDown
        PyridoxalDropDownLabel          matlab.ui.control.Label
        PyridoxalDropDown               matlab.ui.control.DropDown
        Pyridoxal5phosphateDropDownLabel  matlab.ui.control.Label
        Pyridoxal5phosphateDropDown     matlab.ui.control.DropDown
        PyridoxamineDropDownLabel       matlab.ui.control.Label
        PyridoxamineDropDown            matlab.ui.control.DropDown
        PyridoxineDropDownLabel         matlab.ui.control.Label
        PyridoxineDropDown              matlab.ui.control.DropDown
        RiboflavinDropDownLabel         matlab.ui.control.Label
        RiboflavinDropDown              matlab.ui.control.DropDown
        ThiaminDropDownLabel            matlab.ui.control.Label
        ThiaminDropDown                 matlab.ui.control.DropDown
        PurinesandpyrimidinesTab        matlab.ui.container.Tab
        AdenineDropDownLabel            matlab.ui.control.Label
        AdenineDropDown                 matlab.ui.control.DropDown
        AdenosineDropDownLabel          matlab.ui.control.Label
        AdenosineDropDown               matlab.ui.control.DropDown
        GuanineDropDownLabel            matlab.ui.control.Label
        GuanineDropDown                 matlab.ui.control.DropDown
        HypoxanthineDropDownLabel       matlab.ui.control.Label
        HypoxanthineDropDown            matlab.ui.control.DropDown
        InosineDropDownLabel            matlab.ui.control.Label
        InosineDropDown                 matlab.ui.control.DropDown
        OrotateDropDownLabel            matlab.ui.control.Label
        OrotateDropDown                 matlab.ui.control.DropDown
        ThymidineDropDownLabel          matlab.ui.control.Label
        ThymidineDropDown               matlab.ui.control.DropDown
        UracilDropDownLabel             matlab.ui.control.Label
        UracilDropDown                  matlab.ui.control.DropDown
        XanthineDropDownLabel           matlab.ui.control.Label
        XanthineDropDown                matlab.ui.control.DropDown
        OtherTab_2                      matlab.ui.container.Tab
        AcetateDropDownLabel            matlab.ui.control.Label
        AcetateDropDown                 matlab.ui.control.DropDown
        CholesterolDropDownLabel        matlab.ui.control.Label
        CholesterolDropDown             matlab.ui.control.DropDown
        CarbondioxideCO2DropDownLabel   matlab.ui.control.Label
        CarbondioxideCO2DropDown        matlab.ui.control.DropDown
        LMethionineSoxideDropDownLabel  matlab.ui.control.Label
        LMethionineSoxideDropDown       matlab.ui.control.DropDown
        AmmoniumNH4DropDownLabel        matlab.ui.control.Label
        AmmoniumNH4DropDown             matlab.ui.control.DropDown
        SulfateSO4DropDownLabel         matlab.ui.control.Label
        SulfateSO4DropDown              matlab.ui.control.DropDown
        SpermidineDropDownLabel         matlab.ui.control.Label
        SpermidineDropDown              matlab.ui.control.DropDown
        PyruvateDropDownLabel           matlab.ui.control.Label
        PyruvateDropDown                matlab.ui.control.DropDown
        HydrogensulfideH2SDropDownLabel  matlab.ui.control.Label
        HydrogensulfideH2SDropDown      matlab.ui.control.DropDown
        PutrescineDropDownLabel         matlab.ui.control.Label
        PutrescineDropDown              matlab.ui.control.DropDown
        BvitaminbiosynthesisTab         matlab.ui.container.Tab
        LoadBvitaminBiosynthesis        matlab.ui.control.Button
        EditMicrobeFermDropdown_2Label_3  matlab.ui.control.Label
        EditMicrobeBvitaminDropdown     matlab.ui.control.DropDown
        BiotinCheckBox                  matlab.ui.control.CheckBox
        FolateCheckBox                  matlab.ui.control.CheckBox
        NiacinCheckBox                  matlab.ui.control.CheckBox
        CobalaminCheckBox               matlab.ui.control.CheckBox
        PantothenateCheckBox            matlab.ui.control.CheckBox
        PyridoxineCheckBox              matlab.ui.control.CheckBox
        RiboflavinCheckBox              matlab.ui.control.CheckBox
        ThiamineCheckBox                matlab.ui.control.CheckBox
        OverviewofBvitaminBiosyntesisinMicrobeListLabel  matlab.ui.control.Label
        BvitaminBiosynthesisTable       matlab.ui.control.Table
        SavedataButtonBvitamin          matlab.ui.control.Button
        EnterDataButtonBvitamin         matlab.ui.control.Button
    end


    properties (Access = private)
        tables% list of all data tables
        fermBoxes% list of fermentation pathway check boxes
        carbBoxes% list of carbon source check boxes
        nutrDropDown% list of nutrient drop down lists
        BvitaminBoxes% list of B-vitamin biosynthesis check boxes
    end
    
    methods (Access = private)
        
        function updateTables(app)
            % get a unique list of all microbes in all tables
            allMicIDs = {};
            for i = 1:length(app.tables)
                if ~isempty(app.(app.tables{i}).Data)
                    allMicIDs = union(allMicIDs, app.(app.tables{i}).Data(:, 1));
                end
            end
            
            % Update tables
            if ~isempty(allMicIDs)
                for i = 1:length(app.tables)
                    if ~isempty(app.(app.tables{i}).Data)
                        newMics = setdiff(allMicIDs, app.(app.tables{i}).Data(:, 1));
                        if ~isempty(newMics)
                            [rowNum, colNum] = size(app.(app.tables{i}).Data);
                            app.(app.tables{i}).Data(rowNum+1 : rowNum+length(newMics), 1) = newMics;
                            
                            % fill table
                            if any(ismember({'FermentationTable', 'CarbonSourcesTable', 'NutrientRequirementsTable', 'BvitaminBiosynthesisTable'}, app.tables{i}))
                                app.(app.tables{i}).Data(rowNum+1 : rowNum+length(newMics), 2:colNum) = num2cell(zeros(length(newMics), colNum-1));
                            end
                        end
                    else
                        % no data in table yet
                        newMics = allMicIDs;
                        if ~isempty(newMics)
                            [rowNum, colNum] = size(app.(app.tables{i}).Data);
                            app.(app.tables{i}).Data(rowNum+1 : rowNum+length(newMics), ...
                                ismember(app.(app.tables{i}).ColumnName, 'Microbe ID')) ...
                                = newMics;
                            if any(ismember({'FermentationTable', 'CarbonSourcesTable', 'NutrientRequirementsTable', 'BvitaminBiosynthesisTable'}, app.tables{i}))
                                app.(app.tables{i}).Data(rowNum+1 : rowNum+length(newMics), 2:colNum) = num2cell(zeros(length(newMics), colNum-1));
                            end
                        end
                    end
                end
            end
            
           % update EditMicrobesDropdown lists
           app.EditMicrobeInfoDropdown.Items = ['New microbe'; allMicIDs];
           app.EditMicrobeFermDropdown.Items = ['Select microbe'; allMicIDs];
           app.EditMicrobeCarbDropdown.Items = ['Select microbe'; allMicIDs];
           app.EditMicrobeNutrDropdown.Items = ['Select microbe'; allMicIDs];
           app.EditMicrobeBvitaminDropdown.Items = ['Select microbe'; allMicIDs];
           
           % resize columns based on data
           for i = 1:length(app.tables)
               if ~isempty(app.(app.tables{i}).Data)
                   if strcmp(app.tables{i}, 'MicrobesTable')
                       app.(app.tables{i}).ColumnWidth = num2cell(ones(1, size(app.(app.tables{i}).Data, 2)));
                       for j = 1:size(app.(app.tables{i}).Data, 2)
                           [~, maxLength] = max(cellfun(@length, app.(app.tables{i}).Data(:, j)));
                           temp = uicontrol('Visible', 'off', 'Style', 'edit', 'Units', 'pixels', ...
                               'FontSize', 12, 'FontName', 'Helvetica', 'String', app.(app.tables{i}).Data{maxLength(1), j});
                           colPixels = get(temp, 'Extent');
                           close all
                           app.(app.tables{i}).ColumnWidth{j} = colPixels(3);
                       end
                   else
                       app.(app.tables{i}).ColumnWidth = repmat({'auto'}, 1, size(app.(app.tables{i}).Data, 2));
                       app.(app.tables{i}).ColumnWidth{1} = app.MicrobesTable.ColumnWidth{1};
                   end
               end
           end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set working directory to InputFiles
            % first check if it exists or if that's the current directory. Otherwise, create.
            if isempty(strfind(pwd, 'InputFiles'))% not in InputFiles directory
                if ~isdir(['..' filesep 'InputFiles'])% InputFiles not a directory in parent directory
                    if ~isdir([pwd filesep 'InputFiles'])% InputFiles is not a subdirectory in current directory
                        mkdir([pwd filesep 'InputFiles'])% make InputFiles directory
                    end
                    fileDir = [pwd filesep 'InputFiles'];% InputFiles is a subdirectory in current directory
                else
                    fileDir = ['..' filesep 'InputFiles'];% InputFiles is a directory in parent directory
                end  
            else
                fileDir = pwd;% already in InputFiles directory
            end
            
            % check if data files exist in working directory, load file if exists, create new file if not
            % microbe information
            if exist([fileDir filesep 'MicrobesTable.txt'], 'file') == 0
                disp('File ''MicrobesTable.txt'' not found in directory. Creating file.')
                fid = fopen([fileDir filesep 'MicrobesTable.txt'], 'w');
                fprintf(fid, [repmat('%s\t', 1, 8) '%s\n'], 'Microbe ID', 'NCBI Taxonomy', 'Kingdom', 'Phylum', 'Class', ...
                    'Order', 'Family', 'Genus', 'Species');
                fclose(fid);
            end
            % fermentation pathways
            if exist([fileDir filesep 'FermentationTable.txt'], 'file') == 0
                disp('File ''FermentationTable.txt'' not found in directory. Creating file.')
                fid = fopen([fileDir filesep 'FermentationTable.txt'], 'w');
                fprintf(fid, [repmat('%s\t', 1, 28) '%s\n'], ...
                    'Microbe ID', 'Acetate kinase (acetate producer or consumer)', 'Bifid shunt', 'Acetogen pathway', ...
                    'Formate producer', 'D-lactate producer or consumer', 'L-lactate producer or consumer', ...
                    'Ethanol producer or consumer', 'Succinate producer', 'Propionate from succinate', ...
                    'Propionate from propane-1,2-diol', 'Propionate from lactate (acrylate pathway)', ...
                    'Propionate from threonine', 'Butyrate via butyryl-CoA: acetate CoA transferase', ...
                    'Butyrate via butyrate kinase', 'Butyrate from lysine via butyrate-acetoacetate CoA-transferase', ...
                    'Butyrate from glutarate or glutamate', 'Butyrate from 4-hydroxybutyrate or succinate', ...
                    'Hydrogen from ferredoxin oxidoreductase', 'Hydrogen from formate hydrogen lyase', ...
                    'Methanogenesis', 'Sulfate reducer', 'Isobutyrate producer', 'Isovalerate producer', ...
                    'Acetoin producer', '2,3-butanediol producer', 'Indole producer', 'Phenylacetate producer', ...
                    'Butanol producer');
                fclose(fid);
            end
            % carbon sources
            if exist([fileDir filesep 'CarbonSourcesTable.txt'], 'file') == 0
                disp('File ''CarbonSourcesTable.txt'' not found in directory. Creating file.')
                fid = fopen([fileDir filesep 'CarbonSourcesTable.txt'], 'w');
                fprintf(fid, [repmat('%s\t', 1, 95) '%s\n'], ...
                    'Microbe ID', '2-oxoglutarate', 'alpha-Mannan', 'L-alanine', 'Amylopectin', 'Amylose', ...
                    'Arabinan', 'Arabinogalactan', 'D-arabinose', 'L-arabinose', 'Arabinoxylan', 'L-arginine', ...
                    'L-asparagine', 'L-aspartate', 'beta-Glucan', 'Butyrate', 'Cellobiose', 'Cellulose', ...
                    'Choline', 'Chondroitin sulfate', 'Citrate', 'L-cysteine', 'Deoxyribose', 'Dextran', ...
                    'Fructooligosaccarides', 'Fructose', 'L-fucose', 'Fumarate', 'Galactomannan', 'Galactose', ...
                    'D-galacturonic acid', 'Glucomannan', 'D-gluconate (Entner-Doudoroff pathway)', 'D-glucosamine', ...
                    'D-glucose', 'D-glucuronic acid', 'L-glutamate', 'L-glutamine', 'Glycerol', 'Glycogen', 'Heparin', ...
                    'L-histidine', 'Homogalacturonan', 'Hyaluronan', 'Inulin', 'Isobutyrate', 'L-isoleucine', 'Isovalerate', ...
                    'Lactose', 'Laminarin', 'Lichenin', 'L-leucine', 'Levan', 'L-lysine', 'L-lyxose', 'L-malate', 'D-maltose', ...
                    'Mannitol', 'Mannose', 'Melibiose', 'L-methionine', 'Mucin', 'N-acetylgalactosamine', 'N-acetylglucosamine', ...
                    'N-acetylneuraminic acid', 'L-ornithine', 'Oxalate', 'Oxaloacetate', 'Pectic galactan', 'Pectin', 'Phenylacetate', ...
                    'L-phenylalanine', 'L-proline', 'Propionate', 'Pullulan', 'Pyruvate', 'Raffinose', 'Resistant starch', ...
                    'Rhamnogalacturonan I', 'Rhamnogalacturonan II', 'L-rhamnose', 'D-ribose', 'Salicin', 'L-serine', 'Starch', ...
                    'Stickland reaction', 'Succinate', 'Sucrose', 'Trehalose', 'L-threonine', 'L-tryptophan', 'L-tyrosine', ...
                    'L-valine', 'Xylan', 'Xyloglucan', 'D-xylose');
                fclose(fid);
            end
            % Nutrient requirements
            if exist([fileDir filesep 'NutrientRequirementsTable.txt'], 'file') == 0
                disp('File ''NutrientRequirementsTable.txt'' not found in directory. Creating file.')
                fid = fopen([fileDir filesep 'NutrientRequirementsTable.txt'], 'w');
                fprintf(fid, [repmat('%s\t', 1, 54) '%s\n'], ...
                    'Microbe ID', '4-aminobenzoic acid', 'Acetate', 'Adenine', 'Adenosine', 'Ammonium (NH4)', ...
                    'Biotin', 'Carbon dioxide (CO2)', 'Cholesterol', 'Cobalamin', 'Folate', 'Glycine', ...
                    'Guanine', 'Hemin', 'Hydrogen sulfide (H2S)', 'Hypoxanthine', 'Inosine', 'L-alanine', ...
                    'L-arginine', 'L-asparagine', 'L-aspartate', 'L-cysteine', 'L-glutamate', 'L-glutamine', ...
                    'L-histidine', 'L-isoleucine', 'L-leucine', 'L-lysine', 'L-methionine', 'L-Methionine S-oxide', ...
                    'L-phenylalanine', 'L-proline', 'L-serine', 'L-threonine', 'L-tryptophan', 'L-tyrosine', ...
                    'L-valine', 'Nicotinamide', 'Nicotinic acid', 'Ornithine', 'Orotate', 'Pantothenate', ...
                    'Putrescine', 'Pyridoxal', 'Pyridoxal 5-phosphate', 'Pyridoxamine', 'Pyridoxine', 'Pyruvate', ...
                    'Riboflavin', 'Spermidine', 'Sulfate (SO4)', 'Thiamin', 'Thymidine', 'Uracil', 'Xanthine');
                fclose(fid);
            end
            % B-vitamin biosynthesis
            if exist([fileDir filesep 'BvitaminBiosynthesisTable.txt'], 'file') == 0
                disp('File ''BvitaminBiosynthesisTable.txt'' not found in directory. Creating file.')
                fid = fopen([fileDir filesep 'BvitaminBiosynthesisTable.txt'], 'w');
                fprintf(fid, [repmat('%s\t', 1, 8) '%s\n'], ...
                    'Microbe ID', 'Biotin', 'Cobalamin', 'Folate', 'Niacin', 'Pantothenate', 'Pyridoxine', 'Riboflavin', 'Thiamine');
                fclose(fid);
            end
            
            % read files and extract data
            app.tables = {'MicrobesTable', 'FermentationTable', 'CarbonSourcesTable', 'NutrientRequirementsTable', 'BvitaminBiosynthesisTable'};
            for i = 1:length(app.tables)
                tmp = readtable([fileDir filesep app.tables{i} '.txt'], 'Delimiter', '\t');
                header = strrep(strrep(tmp.Properties.VariableDescriptions, ...
                    'Original column heading: ', ''), '''', '');
                header(cellfun(@isempty, header)) = tmp.Properties.VariableNames(cellfun(@isempty, header));            
                % fill table
                app.(app.tables{i}).ColumnName = header;
                app.(app.tables{i}).ColumnEditable = false(1, length(header));
                app.(app.tables{i}).Data = table2cell(tmp);
                
                % read ncbi ids as strings
                if strcmp(app.tables{i}, 'MicrobesTable')
                    app.(app.tables{i}).Data(:, 2) = cellfun(@num2str, app.(app.tables{i}).Data(:, 2), 'UniformOutput', false);
                end
            end
            
            % Fermentation pathway checkboxes
            app.fermBoxes = strcat(regexprep(app.FermentationTable.ColumnName(2:end), '[-,()\s:]', ''), 'CheckBox');
            % box name cannot start with a number
            [~, stp] = regexp(app.fermBoxes, '^\d+');
            if any(~cellfun(@isempty, stp))
                numStart = find(~cellfun(@isempty, stp));
                for i = 1:length(numStart)
                    app.fermBoxes{numStart(i)} = app.fermBoxes{numStart(i)}(stp{numStart(i)}+1:end);
                end
            end
            
            % Carbon source checkboxes
            app.carbBoxes = strcat(regexprep(app.CarbonSourcesTable.ColumnName(2:end), '[-,()\s:]', ''), 'CheckBox');
            % box name cannot start with a number
            [~, stp] = regexp(app.carbBoxes, '^\d+');
            if any(~cellfun(@isempty, stp))
                numStart = find(~cellfun(@isempty, stp));
                for i = 1:length(numStart)
                    app.carbBoxes{numStart(i)} = app.carbBoxes{numStart(i)}(stp{numStart(i)}+1:end);
                end
            end
            
            % Nutrient drop down list
            app.nutrDropDown = strcat(regexprep(app.NutrientRequirementsTable.ColumnName(2:end), '[-,()\s:]', ''), {'DropDown'});
            % box name cannot start with a number
            [~, stp] = regexp(app.nutrDropDown, '^\d+');
            if any(~cellfun(@isempty, stp))
                numStart = find(~cellfun(@isempty, stp));
                for i = 1:length(numStart)
                    app.nutrDropDown{numStart(i)} = app.nutrDropDown{numStart(i)}(stp{numStart(i)}+1:end);
                end
            end
            
            % B-vitamin biosynthesis checkboxes
            app.BvitaminBoxes = strcat(regexprep(app.BvitaminBiosynthesisTable.ColumnName(2:end), '[-,()\s:]', ''), 'CheckBox');
            
            % update tables
            app.updateTables
            
        end

        % Button pushed function: LoadNCBItaxonomyButton
        function LoadNCBItaxonomyButtonPushed(app, event)
            if ~isempty(app.NCBITaxonomyIDEditField.Value)
                hmtlString = urlread(['https://www.ncbi.nlm.nih.gov/Taxonomy/Browser/wwwtax.cgi?id=', app.NCBITaxonomyIDEditField.Value]);
                taxonomy = {
                    'superkingdom'
                    'phylum'
                    'class'
                    'order'
                    'family'
                    'genus'
                    'species'};
                
                % extract information from html string and fill in data
                for i = 1:length(taxonomy)
                    n = regexp(hmtlString, ['TITLE="', taxonomy{i}, '">([^<]+)'], 'tokens');
                    if ~isempty(n)
                        n = [n{:}];
                        if ~isempty(n{1, 1})
                            app.([taxonomy{i}, 'EditField']).Value = n{1, 1};
                        else
                            app.([taxonomy{i}, 'EditField']).Value = '';
                        end
                    end
                end
                
                % extract strain
                n = regexp(hmtlString, '<title>Taxonomy browser (([^)]+)', 'tokens');
                if ~isempty(n)
                    n = [n{:}];
                    if ~isempty(n{1, 1})
                        % replace any non-word, numeric, or underscore character with underscore, replace multiple underscores with one underscore
                        app.MicrobeIDEditField.Value = regexprep(regexprep(n{1, 1}, '\W+', '_'), '_+', '_');
                    end
                end
            end
        end

        % Button pushed function: UpdatetableButton
        function UpdatetableButtonPushed(app, event)
            % save microbial information that was entered into any tab
            if ~isempty(app.MicrobeIDEditField.Value)
                if ~any(ismember(app.MicrobesTable.Data(:, 1), app.MicrobeIDEditField.Value))
                
                    % add microbe
                    app.MicrobesTable.Data(size(app.MicrobesTable.Data, 1) + 1, :) = cell(1, size(app.MicrobesTable.Data, 2));
                    app.MicrobesTable.Data{size(app.MicrobesTable.Data, 1), 1} = app.MicrobeIDEditField.Value;
                    
                    % update other tables to include new microbe in list
                    app.updateTables
                end
                
                % Update MicrobesTable information
                microbeIndex = find(ismember(app.MicrobesTable.Data(:, 1), app.MicrobeIDEditField.Value));
                if ~isempty(app.NCBITaxonomyIDEditField.Value)
                    app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'NCBI Taxonomy')} = app.NCBITaxonomyIDEditField.Value;
                else
                    app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'NCBI Taxonomy')} = 'NaN';
                end
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Kingdom')} = app.superkingdomEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Phylum')} = app.phylumEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Class')} = app.classEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Order')} = app.orderEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Family')} = app.familyEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Genus')} = app.genusEditField.Value;
                app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Species')} = app.speciesEditField.Value;
            end
            
            % Clear values after pressing button
            app.EditMicrobeInfoDropdown.Value = 'New microbe';
            app.MicrobeIDEditField.Value = '';
            app.NCBITaxonomyIDEditField.Value = '';
            app.superkingdomEditField.Value = '';
            app.phylumEditField.Value = '';
            app.classEditField.Value = '';
            app.orderEditField.Value = '';
            app.familyEditField.Value = '';
            app.genusEditField.Value = '';
            app.speciesEditField.Value = '';
        end

        % Button pushed function: SavedataButtonBvitamin, 
        % SavedataButtonCarb, SavedataButtonFerm, SavedataButtonNutr, 
        % SavedatatofilesButton
        function SavedataButtonPushed(app, event)
            for i = 1:length(app.tables)
                % print header and file using fprintf, some column names are not valid table variables
                fid = fopen([fileDir filesep app.tables{i} '.txt'], 'w');
                header = ['fprintf(fid, ', '''' repmat('%s\t', 1, length(app.(app.tables{i}).ColumnName)-1) '%s\n'', ''', ...
                    strjoin(app.(app.tables{i}).ColumnName, ''', '''), ''');'];
                eval(header)
                if strcmp(app.tables{i}, 'MicrobesTable')
                    for j = 1:size(app.(app.tables{i}).Data, 1)
                        line = ['fprintf(fid, ''' repmat('%s\t', 1, size(app.(app.tables{i}).Data, 2)-1), '%s\n'', ' ...
                           '''', strjoin(app.(app.tables{i}).Data(j, :), ''', '''), ''');'];
                        eval(line)
                    end
                else
                    for j = 1:size(app.(app.tables{i}).Data, 1)
                        fprintf(fid, ['%s\t', repmat('%d\t', 1, size(app.(app.tables{i}).Data, 2)-2), '%d\n'], ...
                            app.(app.tables{i}).Data{j, 1}, cell2mat(app.(app.tables{i}).Data(j, 2:size(app.(app.tables{i}).Data, 2))));
                    end
                end
                fclose(fid);
            end
        end

        % Button pushed function: LoadMicrobeButton
        function LoadMicrobeButtonPushed(app, event)
            if strcmp(app.EditMicrobeInfoDropdown.Value, 'New microbe')
                app.MicrobeIDEditField.Value = '';
                app.NCBITaxonomyIDEditField.Value = '';
                app.superkingdomEditField.Value = '';
                app.phylumEditField.Value = '';
                app.classEditField.Value = '';
                app.orderEditField.Value = '';
                app.familyEditField.Value = '';
                app.genusEditField.Value = '';
                app.speciesEditField.Value = '';
            else
                microbeIndex = find(ismember(app.MicrobesTable.Data(:, 1), app.EditMicrobeInfoDropdown.Value));
                app.MicrobeIDEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Microbe ID')};
                app.NCBITaxonomyIDEditField.Value = num2str(app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'NCBI Taxonomy')});
                app.superkingdomEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Kingdom')};
                app.phylumEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Phylum')};
                app.classEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Class')};
                app.orderEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Order')};
                app.familyEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Family')};
                app.genusEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Genus')};
                app.speciesEditField.Value = app.MicrobesTable.Data{microbeIndex, ismember(app.MicrobesTable.ColumnName, 'Species')};
            end
        end

        % Button pushed function: LoadFermentationPathways
        function LoadFermentationPathwaysButtonPushed(app, event)
            % first uncheck all boxes
            for i = 1:length(app.fermBoxes)
                app.(app.fermBoxes{i}).Value = 0;
            end
            
            if ~strcmp(app.EditMicrobeFermDropdown.Value, 'Select microbe')
                microbeIndex = ismember(app.FermentationTable.Data(:, 1), app.EditMicrobeFermDropdown.Value);
                fermProds = find(cell2mat(app.FermentationTable.Data(microbeIndex, 2:size(app.FermentationTable.Data, 2))));
                if ~isempty(fermProds)
                    % check boxes
                    for i = 1:length(fermProds)
                        app.(app.fermBoxes{fermProds(i)}).Value = 1;
                    end
                end
            end
        end

        % Button pushed function: EnterDataButtonCarb
        function EnterDataButtonCarbPushed(app, event)
            % update carbon source table
            microbeIndex = ismember(app.CarbonSourcesTable.Data(:, 1), app.EditMicrobeCarbDropdown.Value);
            
            for i = 1:length(app.carbBoxes)
                % enter data into table
                app.CarbonSourcesTable.Data{microbeIndex, i+1} = double(app.(app.carbBoxes{i}).Value);
                % clear box
                app.(app.carbBoxes{i}).Value = 0;
            end
            
            app.EditMicrobeCarbDropdown.Value = 'Select microbe';
        end

        % Button pushed function: EnterDataButtonFerm
        function EnterDataButtonFermPushed(app, event)
            % update fermentation table
            microbeIndex = ismember(app.FermentationTable.Data(:, 1), app.EditMicrobeFermDropdown.Value);
            
            for i = 1:length(app.fermBoxes)
                % enter data into table
                app.FermentationTable.Data{microbeIndex, i+1} = double(app.(app.fermBoxes{i}).Value);
                % clear box
                app.(app.fermBoxes{i}).Value = 0;
            end
            
            app.EditMicrobeFermDropdown.Value = 'Select microbe';
        end

        % Button pushed function: LoadCarbonSources
        function LoadCarbonSourcesButtonPushed(app, event)
             % first uncheck all boxes
            for i = 1:length(app.carbBoxes)
                app.(app.carbBoxes{i}).Value = 0;
            end
            
            if ~strcmp(app.EditMicrobeCarbDropdown.Value, 'Select microbe')
                microbeIndex = ismember(app.CarbonSourcesTable.Data(:, 1), app.EditMicrobeCarbDropdown.Value);
                carbSources = find(cell2mat(app.CarbonSourcesTable.Data(microbeIndex, 2:size(app.CarbonSourcesTable.Data, 2))));
                if ~isempty(carbSources)
                    % check boxes
                    for i = 1:length(carbSources)
                        app.(app.carbBoxes{carbSources(i)}).Value = 1;
                    end
                end
            end
        end

        % Button pushed function: LoadNutrientRequirements
        function LoadNutrientRequirementsButtonPushed(app, event)
            states = {'No data', 'Non-essential', 'Essential'};
            
            % first set all drop down lists to No data
            for i = 1:length(app.nutrDropDown)
                app.(app.nutrDropDown{i}).Value = states{1};
            end
            
            if ~strcmp(app.EditMicrobeNutrDropdown.Value, 'Select microbe')
                microbeIndex = ismember(app.NutrientRequirementsTable.Data(:, 1), app.EditMicrobeNutrDropdown.Value);
                nessNutrients = find(cell2mat(app.NutrientRequirementsTable.Data(microbeIndex, 2:size(app.NutrientRequirementsTable.Data, 2))) == -1);
                if ~isempty(nessNutrients)
                    for i = 1:length(nessNutrients)
                        app.(app.nutrDropDown{nessNutrients(i)}).Value = states{2};
                    end
                end
                
                essNutrients = find(cell2mat(app.NutrientRequirementsTable.Data(microbeIndex, 2:size(app.NutrientRequirementsTable.Data, 2))) == 1);
                if ~isempty(essNutrients)
                    for i = 1:length(essNutrients)
                        app.(app.nutrDropDown{essNutrients(i)}).Value = states{3};
                    end
                end
            end
        end

        % Button pushed function: EnterDataButtonNutr
        function EnterDataButtonNutrPushed(app, event)
            states = {'No data', 'Non-essential', 'Essential'};
            
            % update nutrient requirements table
            microbeIndex = ismember(app.NutrientRequirementsTable.Data(:, 1), app.EditMicrobeNutrDropdown.Value);
            
            % enter data into table
            for i = 1:length(app.nutrDropDown)
                if strcmp(app.(app.nutrDropDown{i}).Value, states{3})
                    app.NutrientRequirementsTable.Data{microbeIndex, i+1} = 1;
                elseif strcmp(app.(app.nutrDropDown{i}).Value, states{2})
                    app.NutrientRequirementsTable.Data{microbeIndex, i+1} = -1;
                else
                    app.NutrientRequirementsTable.Data{microbeIndex, i+1} = 0;
                end
                
                % set drop down value to No data
                app.(app.nutrDropDown{i}).Value = states{1};
            end
            
            app.EditMicrobeNutrDropdown.Value = 'Select microbe';
        end

        % Button pushed function: EnterDataButtonBvitamin
        function EnterDataButtonBvitaminPushed(app, event)
            % update B-vitamin biosynthesis table
            microbeIndex = ismember(app.BvitaminBiosynthesisTable.Data(:, 1), app.EditMicrobeBvitaminDropdown.Value);
            
            for i = 1:length(app.BvitaminBoxes)
                % enter data into table
                app.BvitaminBiosynthesisTable.Data{microbeIndex, i+1} = double(app.(app.BvitaminBoxes{i}).Value);
                % clear box
                app.(app.BvitaminBoxes{i}).Value = 0;
            end
            
            app.EditMicrobeBvitaminDropdown.Value = 'Select microbe';
        end

        % Button pushed function: LoadBvitaminBiosynthesis
        function LoadBvitaminBiosynthesisButtonPushed(app, event)
            % first uncheck all boxes
            for i = 1:length(app.BvitaminBoxes)
                app.(app.BvitaminBoxes{i}).Value = 0;
            end
            
            if ~strcmp(app.EditMicrobeBvitaminDropdown.Value, 'Select microbe')
                microbeIndex = ismember(app.BvitaminBiosynthesisTable.Data(:, 1), app.EditMicrobeBvitaminDropdown.Value);
                Bvits = find(cell2mat(app.BvitaminBiosynthesisTable.Data(microbeIndex, 2:size(app.BvitaminBiosynthesisTable.Data, 2))));
                if ~isempty(Bvits)
                    % check boxes
                    for i = 1:length(Bvits)
                        app.(app.BvitaminBoxes{Bvits(i)}).Value = 1;
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MicrobesTab = uitab(app.TabGroup);
            app.MicrobesTab.Title = 'Microbes';
            app.MicrobesTab.Units = 'pixels';
            app.MicrobesTable = uitable(app.MicrobesTab);
            app.MicrobesTable.ColumnName = {'Microbe ID'; 'Model ID'; 'Column 3'; 'Column 4'};
            app.MicrobesTable.RowName = {};
            app.MicrobesTableLabel = uilabel(app.MicrobesTab);
            app.MicrobesTableLabel.Text = 'Microbe Overview';
            app.EditmicrobeLabel = uilabel(app.MicrobesTab);
            app.EditmicrobeLabel.Text = 'Edit microbe';
            app.EditMicrobeInfoDropdown = uidropdown(app.MicrobesTab);
            app.MicrobeIDEditFieldLabel = uilabel(app.MicrobesTab);
            app.MicrobeIDEditFieldLabel.Text = 'Microbe ID';
            app.MicrobeIDEditField = uieditfield(app.MicrobesTab, 'text');
            app.LoadMicrobeButton = uibutton(app.MicrobesTab, 'push');
            app.LoadMicrobeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMicrobeButtonPushed, true);
            app.LoadMicrobeButton.Text = 'Load microbe information';
            app.NCBITaxonomyIDEditFieldLabel = uilabel(app.MicrobesTab);
            app.NCBITaxonomyIDEditFieldLabel.Text = 'NCBI Taxonomy ID';
            app.NCBITaxonomyIDEditField = uieditfield(app.MicrobesTab, 'text');
            app.KingdomEditFieldLabel = uilabel(app.MicrobesTab);
            app.KingdomEditFieldLabel.Text = 'Kingdom';
            app.superkingdomEditField = uieditfield(app.MicrobesTab, 'text');
            app.PhylumEditFieldLabel = uilabel(app.MicrobesTab);
            app.PhylumEditFieldLabel.Text = 'Phylum';
            app.phylumEditField = uieditfield(app.MicrobesTab, 'text');
            app.ClassEditFieldLabel = uilabel(app.MicrobesTab);
            app.ClassEditFieldLabel.Text = 'Class';
            app.classEditField = uieditfield(app.MicrobesTab, 'text');
            app.OrderEditFieldLabel = uilabel(app.MicrobesTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.orderEditField = uieditfield(app.MicrobesTab, 'text');
            app.FamilyEditFieldLabel = uilabel(app.MicrobesTab);
            app.FamilyEditFieldLabel.Text = 'Family';
            app.familyEditField = uieditfield(app.MicrobesTab, 'text');
            app.GenusEditFieldLabel = uilabel(app.MicrobesTab);
            app.GenusEditFieldLabel.Text = 'Genus';
            app.genusEditField = uieditfield(app.MicrobesTab, 'text');
            app.SpeciesEditFieldLabel = uilabel(app.MicrobesTab);
            app.SpeciesEditFieldLabel.Text = 'Species';
            app.speciesEditField = uieditfield(app.MicrobesTab, 'text');
            app.LoadNCBItaxonomyButton = uibutton(app.MicrobesTab, 'push');
            app.LoadNCBItaxonomyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNCBItaxonomyButtonPushed, true);
            app.LoadNCBItaxonomyButton.Text = 'Load NCBI taxonomy';
            app.UpdatetableButton = uibutton(app.MicrobesTab, 'push');
            app.UpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetableButtonPushed, true);
            app.UpdatetableButton.Text = 'Update table';
            app.SavedatatofilesButton = uibutton(app.MicrobesTab, 'push');
            app.SavedatatofilesButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedatatofilesButton.Text = 'Save data to files';
            app.TaxonomyLabel = uilabel(app.MicrobesTab);
            app.TaxonomyLabel.Text = 'Taxonomy';
            app.FermentationTab = uitab(app.TabGroup);
            app.FermentationTab.Title = 'Fermentation';
            app.FermentationTab.Units = 'pixels';
            app.OverviewofFermentationPathwaysinMicrobeListLabel = uilabel(app.FermentationTab);
            app.OverviewofFermentationPathwaysinMicrobeListLabel.Text = 'Overview of Fermentation Pathways in Microbe List';
            app.EditmicrobeDropDownLabel = uilabel(app.FermentationTab);
            app.EditmicrobeDropDownLabel.Text = 'Edit microbe';
            app.EditMicrobeFermDropdown = uidropdown(app.FermentationTab);
            app.FermentationTable = uitable(app.FermentationTab);
            app.FermentationTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FermentationTable.RowName = {};
            app.AcetatekinaseacetateproducerorconsumerCheckBox = uicheckbox(app.FermentationTab);
            app.AcetatekinaseacetateproducerorconsumerCheckBox.Text = 'Acetate kinase (acetate producer or consumer)';
            app.BifidshuntCheckBox = uicheckbox(app.FermentationTab);
            app.BifidshuntCheckBox.Text = 'Bifid shunt';
            app.AcetogenpathwayCheckBox = uicheckbox(app.FermentationTab);
            app.AcetogenpathwayCheckBox.Text = 'Acetogen pathway';
            app.FormateproducerCheckBox = uicheckbox(app.FermentationTab);
            app.FormateproducerCheckBox.Text = 'Formate producer';
            app.DlactateproducerorconsumerCheckBox = uicheckbox(app.FermentationTab);
            app.DlactateproducerorconsumerCheckBox.Text = 'D-lactate producer or consumer';
            app.LlactateproducerorconsumerCheckBox = uicheckbox(app.FermentationTab);
            app.LlactateproducerorconsumerCheckBox.Text = 'L-lactate producer or consumer';
            app.EthanolproducerorconsumerCheckBox = uicheckbox(app.FermentationTab);
            app.EthanolproducerorconsumerCheckBox.Text = 'Ethanol producer or consumer';
            app.SuccinateproducerCheckBox = uicheckbox(app.FermentationTab);
            app.SuccinateproducerCheckBox.Text = 'Succinate producer';
            app.PropionatefromsuccinateCheckBox = uicheckbox(app.FermentationTab);
            app.PropionatefromsuccinateCheckBox.Text = 'Propionate from succinate';
            app.Propionatefrompropane12diolCheckBox = uicheckbox(app.FermentationTab);
            app.Propionatefrompropane12diolCheckBox.Text = 'Propionate from propane-1,2-diol';
            app.PropionatefromlactateacrylatepathwayCheckBox = uicheckbox(app.FermentationTab);
            app.PropionatefromlactateacrylatepathwayCheckBox.Text = 'Propionate from lactate (acrylate pathway)';
            app.PropionatefromthreonineCheckBox = uicheckbox(app.FermentationTab);
            app.PropionatefromthreonineCheckBox.Text = 'Propionate from threonine';
            app.ButyrateviabutyrylCoAacetateCoAtransferaseCheckBox = uicheckbox(app.FermentationTab);
            app.ButyrateviabutyrylCoAacetateCoAtransferaseCheckBox.Text = 'Butyrate via butyryl-CoA: acetate CoA transferase';
            app.ButyrateviabutyratekinaseCheckBox = uicheckbox(app.FermentationTab);
            app.ButyrateviabutyratekinaseCheckBox.Text = 'Butyrate via butyrate kinase';
            app.ButyratefromlysineviabutyrateacetoacetateCoAtransferaseCheckBox = uicheckbox(app.FermentationTab);
            app.ButyratefromlysineviabutyrateacetoacetateCoAtransferaseCheckBox.Text = 'Butyrate from lysine via butyrate-acetoacetate CoA-transferase';
            app.ButyratefromglutarateorglutamateCheckBox = uicheckbox(app.FermentationTab);
            app.ButyratefromglutarateorglutamateCheckBox.Text = 'Butyrate from glutarate or glutamate';
            app.Butyratefrom4hydroxybutyrateorsuccinateCheckBox = uicheckbox(app.FermentationTab);
            app.Butyratefrom4hydroxybutyrateorsuccinateCheckBox.Text = 'Butyrate from 4-hydroxybutyrate or succinate';
            app.HydrogenfromferredoxinoxidoreductaseCheckBox = uicheckbox(app.FermentationTab);
            app.HydrogenfromferredoxinoxidoreductaseCheckBox.Text = 'Hydrogen from ferredoxin oxidoreductase';
            app.HydrogenfromformatehydrogenlyaseCheckBox = uicheckbox(app.FermentationTab);
            app.HydrogenfromformatehydrogenlyaseCheckBox.Text = 'Hydrogen from formate hydrogen lyase';
            app.MethanogenesisCheckBox = uicheckbox(app.FermentationTab);
            app.MethanogenesisCheckBox.Text = 'Methanogenesis';
            app.SulfatereducerCheckBox = uicheckbox(app.FermentationTab);
            app.SulfatereducerCheckBox.Text = 'Sulfate reducer';
            app.IsobutyrateproducerCheckBox = uicheckbox(app.FermentationTab);
            app.IsobutyrateproducerCheckBox.Text = 'Isobutyrate producer';
            app.IsovalerateproducerCheckBox = uicheckbox(app.FermentationTab);
            app.IsovalerateproducerCheckBox.Text = 'Isovalerate producer';
            app.AcetoinproducerCheckBox = uicheckbox(app.FermentationTab);
            app.AcetoinproducerCheckBox.Text = 'Acetoin producer';
            app.butanediolproducerCheckBox = uicheckbox(app.FermentationTab);
            app.butanediolproducerCheckBox.Text = '2,3-butanediol producer';
            app.IndoleproducerCheckBox = uicheckbox(app.FermentationTab);
            app.IndoleproducerCheckBox.Text = 'Indole producer';
            app.PhenylacetateproducerCheckBox = uicheckbox(app.FermentationTab);
            app.PhenylacetateproducerCheckBox.Text = 'Phenylacetate producer';
            app.ButanolproducerCheckBox = uicheckbox(app.FermentationTab);
            app.ButanolproducerCheckBox.Text = 'Butanol producer';
            app.LoadFermentationPathways = uibutton(app.FermentationTab, 'push');
            app.LoadFermentationPathways.ButtonPushedFcn = createCallbackFcn(app, @LoadFermentationPathwaysButtonPushed, true);
            app.LoadFermentationPathways.Text = 'Load fermentation pathways';
            app.SavedataButtonFerm = uibutton(app.FermentationTab, 'push');
            app.SavedataButtonFerm.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButtonFerm.Text = 'Save data to files';
            app.EnterDataButtonFerm = uibutton(app.FermentationTab, 'push');
            app.EnterDataButtonFerm.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonFermPushed, true);
            app.EnterDataButtonFerm.Text = 'Update table';
            app.CarbonSourcesTab = uitab(app.TabGroup);
            app.CarbonSourcesTab.Title = 'CarbonSources';
            app.CarbonSourcesTab.Units = 'pixels';
            app.CarbonSourcesTable = uitable(app.CarbonSourcesTab);
            app.CarbonSourcesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CarbonSourcesTable.RowName = {};
            app.OverviewofCarbonSourcesinMicrobeListLabel = uilabel(app.CarbonSourcesTab);
            app.OverviewofCarbonSourcesinMicrobeListLabel.Text = 'Overview of Carbon Sources in Microbe List';
            app.EditMicrobeFermDropdown_2Label_2 = uilabel(app.CarbonSourcesTab);
            app.EditMicrobeFermDropdown_2Label_2.Text = 'Edit microbe';
            app.EditMicrobeCarbDropdown = uidropdown(app.CarbonSourcesTab);
            app.TabGroup2 = uitabgroup(app.CarbonSourcesTab);
            app.SimplesugarsTab = uitab(app.TabGroup2);
            app.SimplesugarsTab.Title = 'Simple sugars';
            app.SimplesugarsTab.Units = 'pixels';
            app.DarabinoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DarabinoseCheckBox.Text = 'D-arabinose';
            app.LarabinoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.LarabinoseCheckBox.Text = 'L-arabinose';
            app.DxyloseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DxyloseCheckBox.Text = 'D-xylose';
            app.SucroseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.SucroseCheckBox.Text = 'Sucrose';
            app.CellobioseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.CellobioseCheckBox.Text = 'Cellobiose';
            app.MannoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.MannoseCheckBox.Text = 'Mannose';
            app.DglucoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DglucoseCheckBox.Text = 'D-glucose';
            app.FructoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.FructoseCheckBox.Text = 'Fructose';
            app.DmaltoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DmaltoseCheckBox.Text = 'D-maltose';
            app.DglucosamineCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DglucosamineCheckBox.Text = 'D-glucosamine';
            app.NacetylglucosamineCheckBox = uicheckbox(app.SimplesugarsTab);
            app.NacetylglucosamineCheckBox.Text = 'N-acetylglucosamine';
            app.DriboseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DriboseCheckBox.Text = 'D-ribose';
            app.GalactoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.GalactoseCheckBox.Text = 'Galactose';
            app.LactoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.LactoseCheckBox.Text = 'Lactose';
            app.TrehaloseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.TrehaloseCheckBox.Text = 'Trehalose';
            app.MannitolCheckBox = uicheckbox(app.SimplesugarsTab);
            app.MannitolCheckBox.Text = 'Mannitol';
            app.MelibioseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.MelibioseCheckBox.Text = 'Melibiose';
            app.RaffinoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.RaffinoseCheckBox.Text = 'Raffinose';
            app.DgluconateEntnerDoudoroffpathwayCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DgluconateEntnerDoudoroffpathwayCheckBox.Text = 'D-gluconate (Entner-Doudoroff pathway)';
            app.SalicinCheckBox = uicheckbox(app.SimplesugarsTab);
            app.SalicinCheckBox.Text = 'Salicin';
            app.DglucuronicacidCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DglucuronicacidCheckBox.Text = 'D-glucuronic acid';
            app.LrhamnoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.LrhamnoseCheckBox.Text = 'L-rhamnose';
            app.DgalacturonicacidCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DgalacturonicacidCheckBox.Text = 'D-galacturonic acid';
            app.LfucoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.LfucoseCheckBox.Text = 'L-fucose';
            app.NacetylneuraminicacidCheckBox = uicheckbox(app.SimplesugarsTab);
            app.NacetylneuraminicacidCheckBox.Text = 'N-acetylneuraminic acid';
            app.NacetylgalactosamineCheckBox = uicheckbox(app.SimplesugarsTab);
            app.NacetylgalactosamineCheckBox.Text = 'N-acetylgalactosamine';
            app.LlyxoseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.LlyxoseCheckBox.Text = 'L-lyxose';
            app.DeoxyriboseCheckBox = uicheckbox(app.SimplesugarsTab);
            app.DeoxyriboseCheckBox.Text = 'Deoxyribose';
            app.OligoandpolysaccharidesTab = uitab(app.TabGroup2);
            app.OligoandpolysaccharidesTab.Title = 'Oligo- and polysaccharides';
            app.OligoandpolysaccharidesTab.Units = 'pixels';
            app.FructooligosaccaridesCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.FructooligosaccaridesCheckBox.Text = 'Fructooligosaccarides';
            app.GlycogenCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.GlycogenCheckBox.Text = 'Glycogen';
            app.InulinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.InulinCheckBox.Text = 'Inulin';
            app.AmylopectinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.AmylopectinCheckBox.Text = 'Amylopectin';
            app.AmyloseCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.AmyloseCheckBox.Text = 'Amylose';
            app.PullulanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.PullulanCheckBox.Text = 'Pullulan';
            app.StarchCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.StarchCheckBox.Text = 'Starch';
            app.XylanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.XylanCheckBox.Text = 'Xylan';
            app.MucinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.MucinCheckBox.Text = 'Mucin';
            app.ArabinogalactanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.ArabinogalactanCheckBox.Text = 'Arabinogalactan';
            app.ArabinoxylanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.ArabinoxylanCheckBox.Text = 'Arabinoxylan';
            app.DextranCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.DextranCheckBox.Text = 'Dextran';
            app.PectinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.PectinCheckBox.Text = 'Pectin';
            app.ArabinanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.ArabinanCheckBox.Text = 'Arabinan';
            app.CelluloseCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.CelluloseCheckBox.Text = 'Cellulose';
            app.HyaluronanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.HyaluronanCheckBox.Text = 'Hyaluronan';
            app.PecticgalactanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.PecticgalactanCheckBox.Text = 'Pectic galactan';
            app.ChondroitinsulfateCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.ChondroitinsulfateCheckBox.Text = 'Chondroitin sulfate';
            app.HeparinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.HeparinCheckBox.Text = 'Heparin';
            app.betaGlucanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.betaGlucanCheckBox.Text = 'beta-Glucan';
            app.GalactomannanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.GalactomannanCheckBox.Text = 'Galactomannan';
            app.LaminarinCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.LaminarinCheckBox.Text = 'Laminarin';
            app.GlucomannanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.GlucomannanCheckBox.Text = 'Glucomannan';
            app.HomogalacturonanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.HomogalacturonanCheckBox.Text = 'Homogalacturonan';
            app.LevanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.LevanCheckBox.Text = 'Levan';
            app.alphaMannanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.alphaMannanCheckBox.Text = 'alpha-Mannan';
            app.LicheninCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.LicheninCheckBox.Text = 'Lichenin';
            app.ResistantstarchCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.ResistantstarchCheckBox.Text = 'Resistant starch';
            app.RhamnogalacturonanICheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.RhamnogalacturonanICheckBox.Text = 'Rhamnogalacturonan I';
            app.RhamnogalacturonanIICheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.RhamnogalacturonanIICheckBox.Text = 'Rhamnogalacturonan II';
            app.XyloglucanCheckBox = uicheckbox(app.OligoandpolysaccharidesTab);
            app.XyloglucanCheckBox.Text = 'Xyloglucan';
            app.AminoacidsTab = uitab(app.TabGroup2);
            app.AminoacidsTab.Title = 'Amino acids';
            app.AminoacidsTab.Units = 'pixels';
            app.SticklandreactionCheckBox = uicheckbox(app.AminoacidsTab);
            app.SticklandreactionCheckBox.Text = 'Stickland reaction';
            app.LalanineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LalanineCheckBox.Text = 'L-alanine';
            app.LarginineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LarginineCheckBox.Text = 'L-arginine';
            app.LasparagineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LasparagineCheckBox.Text = 'L-asparagine';
            app.LaspartateCheckBox = uicheckbox(app.AminoacidsTab);
            app.LaspartateCheckBox.Text = 'L-aspartate';
            app.LcysteineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LcysteineCheckBox.Text = 'L-cysteine';
            app.LglutamateCheckBox = uicheckbox(app.AminoacidsTab);
            app.LglutamateCheckBox.Text = 'L-glutamate';
            app.LglutamineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LglutamineCheckBox.Text = 'L-glutamine';
            app.LhistidineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LhistidineCheckBox.Text = 'L-histidine';
            app.LisoleucineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LisoleucineCheckBox.Text = 'L-isoleucine';
            app.LleucineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LleucineCheckBox.Text = 'L-leucine';
            app.LlysineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LlysineCheckBox.Text = 'L-lysine';
            app.LmethionineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LmethionineCheckBox.Text = 'L-methionine';
            app.LornithineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LornithineCheckBox.Text = 'L-ornithine';
            app.LphenylalanineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LphenylalanineCheckBox.Text = 'L-phenylalanine';
            app.LprolineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LprolineCheckBox.Text = 'L-proline';
            app.LserineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LserineCheckBox.Text = 'L-serine';
            app.LthreonineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LthreonineCheckBox.Text = 'L-threonine';
            app.LtryptophanCheckBox = uicheckbox(app.AminoacidsTab);
            app.LtryptophanCheckBox.Text = 'L-tryptophan';
            app.LtyrosineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LtyrosineCheckBox.Text = 'L-tyrosine';
            app.LvalineCheckBox = uicheckbox(app.AminoacidsTab);
            app.LvalineCheckBox.Text = 'L-valine';
            app.OtherTab = uitab(app.TabGroup2);
            app.OtherTab.Title = 'Other';
            app.OtherTab.Units = 'pixels';
            app.GlycerolCheckBox = uicheckbox(app.OtherTab);
            app.GlycerolCheckBox.Text = 'Glycerol';
            app.SuccinateCheckBox = uicheckbox(app.OtherTab);
            app.SuccinateCheckBox.Text = 'Succinate';
            app.CitrateCheckBox = uicheckbox(app.OtherTab);
            app.CitrateCheckBox.Text = 'Citrate';
            app.FumarateCheckBox = uicheckbox(app.OtherTab);
            app.FumarateCheckBox.Text = 'Fumarate';
            app.LmalateCheckBox = uicheckbox(app.OtherTab);
            app.LmalateCheckBox.Text = 'L-malate';
            app.oxoglutarateCheckBox = uicheckbox(app.OtherTab);
            app.oxoglutarateCheckBox.Text = '2-oxoglutarate';
            app.ButyrateCheckBox = uicheckbox(app.OtherTab);
            app.ButyrateCheckBox.Text = 'Butyrate';
            app.PropionateCheckBox = uicheckbox(app.OtherTab);
            app.PropionateCheckBox.Text = 'Propionate';
            app.PyruvateCheckBox = uicheckbox(app.OtherTab);
            app.PyruvateCheckBox.Text = 'Pyruvate';
            app.CholineCheckBox = uicheckbox(app.OtherTab);
            app.CholineCheckBox.Text = 'Choline';
            app.PhenylacetateCheckBox = uicheckbox(app.OtherTab);
            app.PhenylacetateCheckBox.Text = 'Phenylacetate';
            app.OxaloacetateCheckBox = uicheckbox(app.OtherTab);
            app.OxaloacetateCheckBox.Text = 'Oxaloacetate';
            app.IsobutyrateCheckBox = uicheckbox(app.OtherTab);
            app.IsobutyrateCheckBox.Text = 'Isobutyrate';
            app.IsovalerateCheckBox = uicheckbox(app.OtherTab);
            app.IsovalerateCheckBox.Text = 'Isovalerate';
            app.OxalateCheckBox = uicheckbox(app.OtherTab);
            app.OxalateCheckBox.Text = 'Oxalate';
            app.LoadCarbonSources = uibutton(app.CarbonSourcesTab, 'push');
            app.LoadCarbonSources.ButtonPushedFcn = createCallbackFcn(app, @LoadCarbonSourcesButtonPushed, true);
            app.LoadCarbonSources.Text = 'Load carbon sources';
            app.SavedataButtonCarb = uibutton(app.CarbonSourcesTab, 'push');
            app.SavedataButtonCarb.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButtonCarb.Text = 'Save data to files';
            app.EnterDataButtonCarb = uibutton(app.CarbonSourcesTab, 'push');
            app.EnterDataButtonCarb.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonCarbPushed, true);
            app.EnterDataButtonCarb.Text = 'Update table';
            app.NutrientrequirementsTab = uitab(app.TabGroup);
            app.NutrientrequirementsTab.Title = 'Nutrient requirements';
            app.NutrientrequirementsTab.Units = 'pixels';
            app.LoadNutrientRequirements = uibutton(app.NutrientrequirementsTab, 'push');
            app.LoadNutrientRequirements.ButtonPushedFcn = createCallbackFcn(app, @LoadNutrientRequirementsButtonPushed, true);
            app.LoadNutrientRequirements.Text = 'Load nutrient requirements';
            app.EditMicrobeCarbDropdown_2Label = uilabel(app.NutrientrequirementsTab);
            app.EditMicrobeCarbDropdown_2Label.Text = 'Edit microbe';
            app.EditMicrobeNutrDropdown = uidropdown(app.NutrientrequirementsTab);
            app.NutrientRequirementsTable = uitable(app.NutrientrequirementsTab);
            app.NutrientRequirementsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.NutrientRequirementsTable.RowName = {};
            app.OverviewofNutrientRequirementsinMicrobeListLabel = uilabel(app.NutrientrequirementsTab);
            app.OverviewofNutrientRequirementsinMicrobeListLabel.Text = 'Overview of Nutrient Requirements in Microbe List';
            app.SavedataButtonNutr = uibutton(app.NutrientrequirementsTab, 'push');
            app.SavedataButtonNutr.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButtonNutr.Text = 'Save data to files';
            app.EnterDataButtonNutr = uibutton(app.NutrientrequirementsTab, 'push');
            app.EnterDataButtonNutr.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonNutrPushed, true);
            app.EnterDataButtonNutr.Text = 'Update table';
            app.TabGroup3 = uitabgroup(app.NutrientrequirementsTab);
            app.AminoacidsTab_2 = uitab(app.TabGroup3);
            app.AminoacidsTab_2.Title = 'Amino acids';
            app.AminoacidsTab_2.Units = 'pixels';
            app.LalanineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LalanineDropDownLabel.Text = 'L-alanine';
            app.LalanineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LalanineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LalanineDropDown.Value = 'No data';
            app.LarginineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LarginineDropDownLabel.Text = 'L-arginine';
            app.LarginineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LarginineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LarginineDropDown.Value = 'No data';
            app.LasparagineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LasparagineDropDownLabel.Text = 'L-asparagine';
            app.LasparagineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LasparagineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LasparagineDropDown.Value = 'No data';
            app.LaspartateDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LaspartateDropDownLabel.Text = 'L-aspartate';
            app.LaspartateDropDown = uidropdown(app.AminoacidsTab_2);
            app.LaspartateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LaspartateDropDown.Value = 'No data';
            app.LcysteineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LcysteineDropDownLabel.Text = 'L-cysteine';
            app.LcysteineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LcysteineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LcysteineDropDown.Value = 'No data';
            app.GlycineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.GlycineDropDownLabel.Text = 'Glycine';
            app.GlycineDropDown = uidropdown(app.AminoacidsTab_2);
            app.GlycineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.GlycineDropDown.Value = 'No data';
            app.LglutamineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LglutamineDropDownLabel.Text = 'L-glutamine';
            app.LglutamineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LglutamineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LglutamineDropDown.Value = 'No data';
            app.LhistidineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LhistidineDropDownLabel.Text = 'L-histidine';
            app.LhistidineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LhistidineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LhistidineDropDown.Value = 'No data';
            app.LisoleucineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LisoleucineDropDownLabel.Text = 'L-isoleucine';
            app.LisoleucineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LisoleucineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LisoleucineDropDown.Value = 'No data';
            app.LleucineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LleucineDropDownLabel.Text = 'L-leucine';
            app.LleucineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LleucineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LleucineDropDown.Value = 'No data';
            app.LlysineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LlysineDropDownLabel.Text = 'L-lysine';
            app.LlysineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LlysineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LlysineDropDown.Value = 'No data';
            app.LmethionineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LmethionineDropDownLabel.Text = 'L-methionine';
            app.LmethionineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LmethionineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LmethionineDropDown.Value = 'No data';
            app.OrnithineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.OrnithineDropDownLabel.Text = 'Ornithine';
            app.OrnithineDropDown = uidropdown(app.AminoacidsTab_2);
            app.OrnithineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.OrnithineDropDown.Value = 'No data';
            app.LphenylalanineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LphenylalanineDropDownLabel.Text = 'L-phenylalanine';
            app.LphenylalanineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LphenylalanineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LphenylalanineDropDown.Value = 'No data';
            app.LprolineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LprolineDropDownLabel.Text = 'L-proline';
            app.LprolineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LprolineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LprolineDropDown.Value = 'No data';
            app.LserineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LserineDropDownLabel.Text = 'L-serine';
            app.LserineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LserineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LserineDropDown.Value = 'No data';
            app.LthreonineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LthreonineDropDownLabel.Text = 'L-threonine';
            app.LthreonineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LthreonineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LthreonineDropDown.Value = 'No data';
            app.LtryptophanDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LtryptophanDropDownLabel.Text = 'L-tryptophan';
            app.LtryptophanDropDown = uidropdown(app.AminoacidsTab_2);
            app.LtryptophanDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LtryptophanDropDown.Value = 'No data';
            app.LtyrosineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LtyrosineDropDownLabel.Text = 'L-tyrosine';
            app.LtyrosineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LtyrosineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LtyrosineDropDown.Value = 'No data';
            app.LvalineDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LvalineDropDownLabel.Text = 'L-valine';
            app.LvalineDropDown = uidropdown(app.AminoacidsTab_2);
            app.LvalineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LvalineDropDown.Value = 'No data';
            app.LglutamateDropDownLabel = uilabel(app.AminoacidsTab_2);
            app.LglutamateDropDownLabel.Text = 'L-glutamate';
            app.LglutamateDropDown = uidropdown(app.AminoacidsTab_2);
            app.LglutamateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LglutamateDropDown.Value = 'No data';
            app.VitaminsandcofactorsTab = uitab(app.TabGroup3);
            app.VitaminsandcofactorsTab.Title = 'Vitamins and cofactors';
            app.VitaminsandcofactorsTab.Units = 'pixels';
            app.aminobenzoicacidDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.aminobenzoicacidDropDownLabel.Text = '4-aminobenzoic acid';
            app.aminobenzoicacidDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.aminobenzoicacidDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.aminobenzoicacidDropDown.Value = 'No data';
            app.BiotinDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.BiotinDropDownLabel.Text = 'Biotin';
            app.BiotinDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.BiotinDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.BiotinDropDown.Value = 'No data';
            app.CobalaminDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.CobalaminDropDownLabel.Text = 'Cobalamin';
            app.CobalaminDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.CobalaminDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.CobalaminDropDown.Value = 'No data';
            app.FolateDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.FolateDropDownLabel.Text = 'Folate';
            app.FolateDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.FolateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.FolateDropDown.Value = 'No data';
            app.HeminDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.HeminDropDownLabel.Text = 'Hemin';
            app.HeminDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.HeminDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.HeminDropDown.Value = 'No data';
            app.NicotinamideDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.NicotinamideDropDownLabel.Text = 'Nicotinamide';
            app.NicotinamideDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.NicotinamideDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.NicotinamideDropDown.Value = 'No data';
            app.NicotinicacidDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.NicotinicacidDropDownLabel.Text = 'Nicotinic acid';
            app.NicotinicacidDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.NicotinicacidDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.NicotinicacidDropDown.Value = 'No data';
            app.PantothenateDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.PantothenateDropDownLabel.Text = 'Pantothenate';
            app.PantothenateDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.PantothenateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PantothenateDropDown.Value = 'No data';
            app.PyridoxalDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.PyridoxalDropDownLabel.Text = 'Pyridoxal';
            app.PyridoxalDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.PyridoxalDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PyridoxalDropDown.Value = 'No data';
            app.Pyridoxal5phosphateDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.Pyridoxal5phosphateDropDownLabel.Text = 'Pyridoxal 5-phosphate';
            app.Pyridoxal5phosphateDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.Pyridoxal5phosphateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.Pyridoxal5phosphateDropDown.Value = 'No data';
            app.PyridoxamineDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.PyridoxamineDropDownLabel.Text = 'Pyridoxamine';
            app.PyridoxamineDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.PyridoxamineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PyridoxamineDropDown.Value = 'No data';
            app.PyridoxineDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.PyridoxineDropDownLabel.Text = 'Pyridoxine';
            app.PyridoxineDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.PyridoxineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PyridoxineDropDown.Value = 'No data';
            app.RiboflavinDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.RiboflavinDropDownLabel.Text = 'Riboflavin';
            app.RiboflavinDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.RiboflavinDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.RiboflavinDropDown.Value = 'No data';
            app.ThiaminDropDownLabel = uilabel(app.VitaminsandcofactorsTab);
            app.ThiaminDropDownLabel.Text = 'Thiamin';
            app.ThiaminDropDown = uidropdown(app.VitaminsandcofactorsTab);
            app.ThiaminDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.ThiaminDropDown.Value = 'No data';
            app.PurinesandpyrimidinesTab = uitab(app.TabGroup3);
            app.PurinesandpyrimidinesTab.Title = 'Purines and pyrimidines';
            app.PurinesandpyrimidinesTab.Units = 'pixels';
            app.AdenineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.AdenineDropDownLabel.Text = 'Adenine';
            app.AdenineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.AdenineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.AdenineDropDown.Value = 'No data';
            app.AdenosineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.AdenosineDropDownLabel.Text = 'Adenosine';
            app.AdenosineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.AdenosineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.AdenosineDropDown.Value = 'No data';
            app.GuanineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.GuanineDropDownLabel.Text = 'Guanine';
            app.GuanineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.GuanineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.GuanineDropDown.Value = 'No data';
            app.HypoxanthineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.HypoxanthineDropDownLabel.Text = 'Hypoxanthine';
            app.HypoxanthineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.HypoxanthineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.HypoxanthineDropDown.Value = 'No data';
            app.InosineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.InosineDropDownLabel.Text = 'Inosine';
            app.InosineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.InosineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.InosineDropDown.Value = 'No data';
            app.OrotateDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.OrotateDropDownLabel.Text = 'Orotate';
            app.OrotateDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.OrotateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.OrotateDropDown.Value = 'No data';
            app.ThymidineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.ThymidineDropDownLabel.Text = 'Thymidine';
            app.ThymidineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.ThymidineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.ThymidineDropDown.Value = 'No data';
            app.UracilDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.UracilDropDownLabel.Text = 'Uracil';
            app.UracilDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.UracilDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.UracilDropDown.Value = 'No data';
            app.XanthineDropDownLabel = uilabel(app.PurinesandpyrimidinesTab);
            app.XanthineDropDownLabel.Text = 'Xanthine';
            app.XanthineDropDown = uidropdown(app.PurinesandpyrimidinesTab);
            app.XanthineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.XanthineDropDown.Value = 'No data';
            app.OtherTab_2 = uitab(app.TabGroup3);
            app.OtherTab_2.Title = 'Other';
            app.OtherTab_2.Units = 'pixels';
            app.AcetateDropDownLabel = uilabel(app.OtherTab_2);
            app.AcetateDropDownLabel.Text = 'Acetate';
            app.AcetateDropDown = uidropdown(app.OtherTab_2);
            app.AcetateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.AcetateDropDown.Value = 'No data';
            app.CholesterolDropDownLabel = uilabel(app.OtherTab_2);
            app.CholesterolDropDownLabel.Text = 'Cholesterol';
            app.CholesterolDropDown = uidropdown(app.OtherTab_2);
            app.CholesterolDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.CholesterolDropDown.Value = 'No data';
            app.CarbondioxideCO2DropDownLabel = uilabel(app.OtherTab_2);
            app.CarbondioxideCO2DropDownLabel.Text = 'Carbon dioxide (CO2)';
            app.CarbondioxideCO2DropDown = uidropdown(app.OtherTab_2);
            app.CarbondioxideCO2DropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.CarbondioxideCO2DropDown.Value = 'No data';
            app.LMethionineSoxideDropDownLabel = uilabel(app.OtherTab_2);
            app.LMethionineSoxideDropDownLabel.Text = 'L-Methionine S-oxide';
            app.LMethionineSoxideDropDown = uidropdown(app.OtherTab_2);
            app.LMethionineSoxideDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.LMethionineSoxideDropDown.Value = 'No data';
            app.AmmoniumNH4DropDownLabel = uilabel(app.OtherTab_2);
            app.AmmoniumNH4DropDownLabel.Text = 'Ammonium (NH4)';
            app.AmmoniumNH4DropDown = uidropdown(app.OtherTab_2);
            app.AmmoniumNH4DropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.AmmoniumNH4DropDown.Value = 'No data';
            app.SulfateSO4DropDownLabel = uilabel(app.OtherTab_2);
            app.SulfateSO4DropDownLabel.Text = 'Sulfate (SO4)';
            app.SulfateSO4DropDown = uidropdown(app.OtherTab_2);
            app.SulfateSO4DropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.SulfateSO4DropDown.Value = 'No data';
            app.SpermidineDropDownLabel = uilabel(app.OtherTab_2);
            app.SpermidineDropDownLabel.Text = 'Spermidine';
            app.SpermidineDropDown = uidropdown(app.OtherTab_2);
            app.SpermidineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.SpermidineDropDown.Value = 'No data';
            app.PyruvateDropDownLabel = uilabel(app.OtherTab_2);
            app.PyruvateDropDownLabel.Text = 'Pyruvate';
            app.PyruvateDropDown = uidropdown(app.OtherTab_2);
            app.PyruvateDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PyruvateDropDown.Value = 'No data';
            app.HydrogensulfideH2SDropDownLabel = uilabel(app.OtherTab_2);
            app.HydrogensulfideH2SDropDownLabel.Text = 'Hydrogen sulfide (H2S)';
            app.HydrogensulfideH2SDropDown = uidropdown(app.OtherTab_2);
            app.HydrogensulfideH2SDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.HydrogensulfideH2SDropDown.Value = 'No data';
            app.PutrescineDropDownLabel = uilabel(app.OtherTab_2);
            app.PutrescineDropDownLabel.Text = 'Putrescine';
            app.PutrescineDropDown = uidropdown(app.OtherTab_2);
            app.PutrescineDropDown.Items = {'No data', 'Non-essential', 'Essential'};
            app.PutrescineDropDown.Value = 'No data';
            app.BvitaminbiosynthesisTab = uitab(app.TabGroup);
            app.BvitaminbiosynthesisTab.Title = 'B-vitamin biosynthesis';
            app.BvitaminbiosynthesisTab.Units = 'pixels';
            app.LoadBvitaminBiosynthesis = uibutton(app.BvitaminbiosynthesisTab, 'push');
            app.LoadBvitaminBiosynthesis.ButtonPushedFcn = createCallbackFcn(app, @LoadBvitaminBiosynthesisButtonPushed, true);
            app.LoadBvitaminBiosynthesis.Text = 'Load B-vitamin biosynthesis data';
            app.EditMicrobeFermDropdown_2Label_3 = uilabel(app.BvitaminbiosynthesisTab);
            app.EditMicrobeFermDropdown_2Label_3.Text = 'Edit microbe';
            app.EditMicrobeBvitaminDropdown = uidropdown(app.BvitaminbiosynthesisTab);
            app.BiotinCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.BiotinCheckBox.Text = 'Biotin';
            app.FolateCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.FolateCheckBox.Text = 'Folate';
            app.NiacinCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.NiacinCheckBox.Text = 'Niacin';
            app.CobalaminCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.CobalaminCheckBox.Text = 'Cobalamin';
            app.PantothenateCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.PantothenateCheckBox.Text = 'Pantothenate';
            app.PyridoxineCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.PyridoxineCheckBox.Text = 'Pyridoxine';
            app.RiboflavinCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.RiboflavinCheckBox.Text = 'Riboflavin';
            app.ThiamineCheckBox = uicheckbox(app.BvitaminbiosynthesisTab);
            app.ThiamineCheckBox.Text = 'Thiamine';
            app.OverviewofBvitaminBiosyntesisinMicrobeListLabel = uilabel(app.BvitaminbiosynthesisTab);
            app.OverviewofBvitaminBiosyntesisinMicrobeListLabel.Text = 'Overview of B-vitamin biosynthesis in Microbe List';
            app.BvitaminBiosynthesisTable = uitable(app.BvitaminbiosynthesisTab);
            app.BvitaminBiosynthesisTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BvitaminBiosynthesisTable.RowName = {};
            app.SavedataButtonBvitamin = uibutton(app.BvitaminbiosynthesisTab, 'push');
            app.SavedataButtonBvitamin.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButtonBvitamin.Text = 'Save data to files';
            app.EnterDataButtonBvitamin = uibutton(app.BvitaminbiosynthesisTab, 'push');
            app.EnterDataButtonBvitamin.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonBvitaminPushed, true);
            app.EnterDataButtonBvitamin.Text = 'Update table';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InsertExperimentalData()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end